classdef data_load_gp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        data_loadUIFigure  matlab.ui.Figure
        clear_workspace    matlab.ui.control.Button
        progress_bar       matlab.ui.control.EditField
        parameterButton    matlab.ui.control.Button
        signalButton       matlab.ui.control.Button
        TextArea           matlab.ui.control.TextArea
        Button             matlab.ui.control.Button
    end

    
    properties (Access = public)
        gfilename = '' % Description
    end
    
    methods (Access = private)
        
        function progress_bar_run(app,d,num,position0)
            position = position0;
            position(3) = position0(3) + num * d;
            result_str = num2str(num*100);
            percent_str = '%';
            result_str = strcat(result_str,percent_str);
            app.progress_bar.Value = result_str;
            app.progress_bar.Position = position;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            filename = '';
            [Fnameh,Pnameh] = uigetfile({'*.xlsx;*.xls'});
            filename = [Pnameh,Fnameh];
            app.gfilename = filename;
            app.TextArea.Value = {app.gfilename};
        end

        % Button pushed function: signalButton
        function signalButtonPushed(app, event)
            if strcmp(app.gfilename,'') == 0
%             if app.gfilename ~= ''
            try
                opts_alt = detectImportOptions(app.gfilename,'ExpectedNumVariables',17,'Sheet',"Signals",'DataRange',"A1:Q10000");
                opts_alt.VariableNames = ["ModelName", "PortType", "Name", "DataType", "CustomStorageClass", "DefinitionFile", "RTE_Interface", "Dimensions", "Details", "ValueTable", "Unit", "IniValue", "Min", "Max", "DataTypeSelect", "CustomStorageClassSelect", "DefinitionFile1"];
                opts_alt.VariableTypes = ["string", "categorical", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string"];
                opts_alt = setvaropts(opts_alt, ["Name", "RTE_Interface", "Details", "ValueTable", "Unit", "IniValue", "DataTypeSelect", "CustomStorageClassSelect", "DefinitionFile1"], "WhitespaceRule", "preserve");
                opts_alt = setvaropts(opts_alt, ["ModelName", "PortType", "Name", "DataType", "CustomStorageClass", "DefinitionFile", "RTE_Interface", "Details", "ValueTable", "Unit", "IniValue", "DataTypeSelect", "CustomStorageClassSelect", "DefinitionFile1","Min","Max"], "EmptyFieldRule", "auto");
            catch
                opts_alt = detectImportOptions(app.gfilename,'ExpectedNumVariables',14,'Sheet',"Signals",'DataRange',"A1:N10000");
                opts_alt.VariableNames = ["ModelName", "PortType", "Name", "DataType", "CustomStorageClass", "DefinitionFile", "RTE_Interface", "Dimensions", "Details", "ValueTable", "Unit", "IniValue", "Min", "Max"];
                opts_alt.VariableTypes = ["string", "categorical", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string"];
                opts_alt = setvaropts(opts_alt, ["Name", "RTE_Interface", "Details", "ValueTable", "Unit", "IniValue"], "WhitespaceRule", "preserve");
                opts_alt = setvaropts(opts_alt, ["ModelName", "PortType", "Name", "DataType", "CustomStorageClass", "DefinitionFile", "RTE_Interface", "Details", "ValueTable", "Unit", "IniValue","Min","Max"], "EmptyFieldRule", "auto");
            end

%             %% 设置导入选项并导入数据
%             opts = spreadsheetImportOptions("NumVariables", 17);
%             
%             % 指定工作表和范围
%             opts.Sheet = "Signals";
%             opts.DataRange = "A1:Q10000";
%             
%             % 指定列名称和类型
%             opts.VariableNames = ["ModelName", "PortType", "Name", "DataType", "CustomStorageClass", "DefinitionFile", "RTE_Interface", "Dimensions", "Details", "ValueTable", "Unit", "IniValue", "Min", "Max", "DataTypeSelect", "CustomStorageClassSelect", "DefinitionFile1"];
%             opts.VariableTypes = ["categorical", "categorical", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string"];
%             
%             % 指定变量属性
%             opts = setvaropts(opts, ["Name", "RTE_Interface", "Details", "ValueTable", "Unit", "IniValue", "DataTypeSelect", "CustomStorageClassSelect", "DefinitionFile1"], "WhitespaceRule", "preserve");
%             opts = setvaropts(opts, ["ModelName", "PortType", "Name", "DataType", "CustomStorageClass", "DefinitionFile", "RTE_Interface", "Details", "ValueTable", "Unit", "IniValue", "DataTypeSelect", "CustomStorageClassSelect", "DefinitionFile1","Min","Max"], "EmptyFieldRule", "auto");
            
            % 导入数据
            combineddataS1 = readtable(app.gfilename, opts_alt, "UseExcel", false);
            
            
            %% 清除临时变量
            clear opts_alt
            temp_progress = 0;
            app.progress_bar.Position = [28,189,0,22];
            for i = [2:1:10000]
                temp_progress = i/10000;
                progress_bar_run(app,0.1,temp_progress,app.progress_bar.Position);
                if rem(i,100) == 0
                    drawnow
                end
                sig_attributes = combineddataS1(i,:);
                sig_create_station = strcat(sig_attributes.Name, '=' , 'GeelyPack.Signal',';');
                sig_datatype_assign = strcat(sig_attributes.Name,'.DataType','=','"',sig_attributes.DataType,'"',';');
                sig_storageclass_assign = strcat(sig_attributes.Name,'.CoderInfo.StorageClass','=','"','Custom','"',';');
                sig_customstorageclass_assign = strcat(sig_attributes.Name,'.CoderInfo.CustomStorageClass','=','"',sig_attributes.CustomStorageClass,'"',';');
                sig_dimensions_assign = strcat(sig_attributes.Name,'.Dimensions','=',sig_attributes.Dimensions,';');
                sig_description_assign = strcat(sig_attributes.Name,'.Description','=','"',sig_attributes.Details.erase(char(10)),'"',';');
                sig_unit_assign = strcat(sig_attributes.Name,'.Unit','=','"',sig_attributes.Unit,'"',';');
                sig_initialvalue_assign = strcat(sig_attributes.Name,'.InitialValue','=','"',sig_attributes.IniValue,'"',';');
                sig_minvalue_assign = strcat(sig_attributes.Name,'.Min','=',sig_attributes.Min,';');
                sig_maxvalue_assign = strcat(sig_attributes.Name,'.Max','=',sig_attributes.Max,';');
                sig_owner_assign = strcat(sig_attributes.Name,'.CoderInfo.CustomAttributes.Owner','=','"',sig_attributes.ModelName,'"',';');
                sig_DefinitionFile_assign = strcat(sig_attributes.Name,'.CoderInfo.CustomAttributes.DefinitionFile','=','"',sig_attributes.DefinitionFile,'"',';');
                sig_Header_assign = strcat(sig_attributes.Name,'.CoderInfo.CustomAttributes.HeaderFile','=','"',sig_attributes.DefinitionFile1,'"',';');
                if sig_attributes.Name == ""
                    continue;
                end
                evalin('base',sig_create_station);
                evalin('base',sig_datatype_assign);
                evalin('base',sig_storageclass_assign);
                evalin('base',sig_customstorageclass_assign);
                evalin('base',sig_dimensions_assign);
                evalin('base',sig_description_assign);
                evalin('base',sig_unit_assign);
                if isempty(strfind(sig_attributes.CustomStorageClass,'_IMP'))
                    evalin('base',sig_owner_assign);
                    evalin('base',sig_DefinitionFile_assign);
                end
                evalin('base',sig_Header_assign);
                if sig_attributes.IniValue ~= ""
                    evalin('base',sig_initialvalue_assign);
                end
                if sig_attributes.Min ~= ""
                    evalin('base',sig_minvalue_assign);
                end
                if sig_attributes.Max ~= ""
                    evalin('base',sig_maxvalue_assign);
                end
            end

            
            end
            clear combineddataS1;
            clear i;
            clear sig_attributes;
        

        end

        % Button pushed function: parameterButton
        function parameterButtonPushed(app, event)
            %% 设置导入选项并导入数据
            % clear all;
            % clc;
            if strcmp(app.gfilename,'') == 0
%             if app.gfilename ~= ''
            value_assgine_num = 0;
            try
                opts_alt = detectImportOptions(app.gfilename,'ExpectedNumVariables',17,'Sheet',"Parameters",'DataRange',"A1:Q10000");
                opts_alt.VariableNames = ["ModelName", "PortType", "Name", "DataType", "CustomStorageClass", "DefinitionFile", "RTE_Interface", "Dimensions", "Details", "ValueTable", "Unit", "IniValue", "Min", "Max", "DataTypeSelect", "CustomStorageClassSelect", "DefinitionFile1"];
                opts_alt.VariableTypes = ["string", "categorical", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string"];
                opts_alt = setvaropts(opts_alt, ["Name", "RTE_Interface", "Details", "ValueTable", "Unit", "IniValue", "DataTypeSelect", "CustomStorageClassSelect", "DefinitionFile1"], "WhitespaceRule", "preserve");
                opts_alt = setvaropts(opts_alt, ["ModelName", "PortType", "Name", "DataType", "CustomStorageClass", "DefinitionFile", "RTE_Interface", "Details", "ValueTable", "Unit", "IniValue", "DataTypeSelect", "CustomStorageClassSelect", "DefinitionFile1","Min","Max"], "EmptyFieldRule", "auto");
            catch
                opts_alt = detectImportOptions(app.gfilename,'ExpectedNumVariables',14,'Sheet',"Parameters",'DataRange',"A1:N10000");
                opts_alt.VariableNames = ["ModelName", "PortType", "Name", "DataType", "CustomStorageClass", "DefinitionFile", "RTE_Interface", "Dimensions", "Details", "ValueTable", "Unit", "IniValue", "Min", "Max"];
                opts_alt.VariableTypes = ["string", "categorical", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string"];
                opts_alt = setvaropts(opts_alt, ["Name", "RTE_Interface", "Details", "ValueTable", "Unit", "IniValue"], "WhitespaceRule", "preserve");
                opts_alt = setvaropts(opts_alt, ["ModelName", "PortType", "Name", "DataType", "CustomStorageClass", "DefinitionFile", "RTE_Interface", "Details", "ValueTable", "Unit", "IniValue","Min","Max"], "EmptyFieldRule", "auto");
            end

%             opts = spreadsheetImportOptions("NumVariables", 17);
%             
%             % 指定工作表和范围
%             opts.Sheet = "Parameters";
%             opts.DataRange = "A1:Q10000";
%             
%             % 指定列名称和类型
%             opts.VariableNames = ["ModelName", "PortType", "Name", "DataType", "CustomStorageClass", "DefinitionFile", "RTE_Interface", "Dimensions", "Details", "ValueTable", "Unit", "IniValue", "Min", "Max", "DataTypeSelect", "CustomStorageClassSelect", "DefinitionFile1"];
%             opts.VariableTypes = ["categorical", "categorical", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string", "string"];
%             
%             % 指定变量属性
%             opts = setvaropts(opts, ["Name", "RTE_Interface", "Details", "ValueTable", "Unit", "IniValue", "DataTypeSelect", "CustomStorageClassSelect", "DefinitionFile1"], "WhitespaceRule", "preserve");
%             opts = setvaropts(opts, ["ModelName", "PortType", "Name", "DataType", "CustomStorageClass", "DefinitionFile", "RTE_Interface", "Details", "ValueTable", "Unit", "IniValue", "DataTypeSelect", "CustomStorageClassSelect", "DefinitionFile1","Min","Max"], "EmptyFieldRule", "auto");
%             
            % 导入数据
            combineddataS1 = readtable(app.gfilename, opts_alt, "UseExcel", false);
            
            %% 清除临时变量
            clear opts_alt
            app.progress_bar.Position = [28,189,0,22];
            for i = [2:1:10000]
                temp_progress = i/10000;
                progress_bar_run(app,0.1,temp_progress,app.progress_bar.Position);
                if rem(i,100) == 0
                    drawnow
                end
                para_attributes = combineddataS1(i,:);
                para_create_station = strcat(para_attributes.Name, '=' , 'GeelyPack.Parameter',';');
                para_datatype_assign = strcat(para_attributes.Name,'.DataType','=','"',para_attributes.DataType,'"',';');
                para_storageclass_assign = strcat(para_attributes.Name,'.CoderInfo.StorageClass','=','"','Custom','"',';');
                if para_attributes.CustomStorageClass == 'MacroConstIn_Lotus'
                    para_attributes.CustomStorageClass = 'MacroConstIn';
                end
                para_customstorageclass_assign = strcat(para_attributes.Name,'.CoderInfo.CustomStorageClass','=','"',para_attributes.CustomStorageClass,'"',';');
                para_dimensions_assign = strcat(para_attributes.Name,'.Dimensions','=',para_attributes.Dimensions,';');
                para_description_assign = strcat(para_attributes.Name,'.Description','=','"',para_attributes.Details.erase(char(10)),'"',';');
                para_unit_assign = strcat(para_attributes.Name,'.Unit','=','"',para_attributes.Unit,'"',';');
                para_initialvalue_assign = strcat(para_attributes.Name,'.Value','=',para_attributes.IniValue,';');
                para_minvalue_assign = strcat(para_attributes.Name,'.Min','=',para_attributes.Min,';');
                para_maxvalue_assign = strcat(para_attributes.Name,'.Max','=',para_attributes.Max,';');
                para_definationfile_assign = strcat(para_attributes.Name,'.CoderInfo.CustomAttributes.DefinitionFile','=','"',para_attributes.DefinitionFile,'"',';');
                para_headerfile_assign = strcat(para_attributes.Name,'.CoderInfo.CustomAttributes.HeaderFile','=','"',para_attributes.DefinitionFile1,'"',';');
                para_Owner_assign = strcat(para_attributes.Name,'.CoderInfo.CustomAttributes.Owner','=','"',para_attributes.ModelName,'"',';');
                try
                    if para_attributes.Name == ""
                        continue;
                    end
                    evalin('base',para_create_station);
                    evalin('base',para_datatype_assign);
                    evalin('base',para_storageclass_assign);
                    evalin('base',para_customstorageclass_assign);
                    %         eval(para_dimensions_assign);
                    evalin('base',para_description_assign);
                    evalin('base',para_unit_assign);
                    if para_attributes.IniValue ~= ""
                        evalin('base',para_initialvalue_assign);
                        value_assgine_num = value_assgine_num + 1;
                    end
                    if para_attributes.Min ~= ""
                        evalin('base',para_minvalue_assign);
                    end
                    if para_attributes.Max ~= ""
                        evalin('base',para_maxvalue_assign);
                    end
                    evalin('base',para_definationfile_assign);
                    evalin('base',para_headerfile_assign);
                    evalin('base',para_Owner_assign);
                end
            end
            end
            clear combineddataS1;
            clear i;
            clear para_attributes;
        end

        % Button pushed function: clear_workspace
        function clear_workspacePushed(app, event)
            evalin('base','clear all');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.data_loadUIFigure = uifigure('Visible', 'off');
            app.data_loadUIFigure.Name = 'data_load';
            app.Button = uibutton(app.data_loadUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '选择数据文件';
            app.TextArea = uitextarea(app.data_loadUIFigure);
            app.TextArea.Editable = 'off';
            app.signalButton = uibutton(app.data_loadUIFigure, 'push');
            app.signalButton.ButtonPushedFcn = createCallbackFcn(app, @signalButtonPushed, true);
            app.signalButton.Text = '加载signal';
            app.parameterButton = uibutton(app.data_loadUIFigure, 'push');
            app.parameterButton.ButtonPushedFcn = createCallbackFcn(app, @parameterButtonPushed, true);
            app.parameterButton.Text = '加载parameter';
            app.progress_bar = uieditfield(app.data_loadUIFigure, 'text');
            app.progress_bar.Editable = 'off';
            app.clear_workspace = uibutton(app.data_loadUIFigure, 'push');
            app.clear_workspace.ButtonPushedFcn = createCallbackFcn(app, @clear_workspacePushed, true);
            app.clear_workspace.Text = '清空变量';
            % Show the figure after all components are created
            app.data_loadUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_load_gp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.data_loadUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.data_loadUIFigure)
        end
    end
end