classdef draft_smart_thermal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LogTextArea                matlab.ui.control.TextArea
        TabGroup                   matlab.ui.container.TabGroup
        TestTab                    matlab.ui.container.Tab
        CaseListTree               matlab.ui.container.Tree
        TestHarnessPanel           matlab.ui.container.Panel
        Export2ExcelButton         matlab.ui.control.Button
        NextStepDropDown           matlab.ui.control.DropDown
        NextStepDropDownLabel      matlab.ui.control.Label
        LastStepDropDown           matlab.ui.control.DropDown
        LastStepDropDownLabel      matlab.ui.control.Label
        TestCaseTextArea           matlab.ui.control.TextArea
        TestCaseTextAreaLabel      matlab.ui.control.Label
        createHarnessButton        matlab.ui.control.Button
        GetPortsButton             matlab.ui.control.Button
        AddCaseButton              matlab.ui.control.Button
        HarnessHistoryParamsPanel  matlab.ui.container.Panel
        HarnessParamsListBox       matlab.ui.control.ListBox
        CaseTreePanel              matlab.ui.container.Panel
        CaseTable                  matlab.ui.control.Table
        ContextMenuCaseTab         matlab.ui.container.ContextMenu
        DelSelectMenu              matlab.ui.container.Menu
        DelAllMenu                 matlab.ui.container.Menu
        ContextMenuHarnessParams   matlab.ui.container.ContextMenu
        DelParamMenu               matlab.ui.container.Menu
        ContextMenuCaseTree        matlab.ui.container.ContextMenu
        DelCaseMenu                matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            % 加载默认配置文件
            config = configGet();
            % 将app.DcmFilePath 的文本内容，设置成dcm_file
            app.DcmFilePath.Value = config.dcm_file;
            app.STAGEEditField.Value = config.stage;
            app.INTERFACE_VERISONEditField.Value = config.interface_version;
            app.SOFT_VERSIONEditField.Value = config.soft_version;
            app.VER_NAMEEditField.Value = config.vername;
            app.SlddDropDown.Items = config.com_used_model;
            app.SubModDropDown.Items = config.com_used_model;
            app.MCUTypeDropDown.Items = config.com_used_controllers;
            app.MCUTypeDropDown.Value = config.active_controller;


            app.GotoWidEditField.Value = config.GotoWid;
            app.GotoHeightEditField.Value = config.GotoHeight;
            app.GotoColorEditField.Value = config.GotoColor;
            app.FromColorEditField.Value = config.FromColor;
            
            app.PortWidEditField.Value = config.PortWid;
            app.PortHeightEditField.Value = config.PortHeight;
            app.InportColorEditField.Value = config.InportColor;
            app.OutportColorEditField.Value = config.OutportColor;

            app.CommonUsedModelsTextArea.Value = config.com_used_model;
            app.CommonUsedContorlersTextArea.Value = config.com_used_controllers;


        end

        % Callback function
        function SetDefaultDcmButtonPushed(app, event)
            % select file through gui
            dcm_file = uigetfile('*.dcm', 'Select DICOM File');
            if isequal(dcm_file, 0)
                % User cancelled the dialog box.
                return;
            end
            % 将app.DcmFilePath 的文本内容，设置成dcm_file
            app.DcmFilePath.Value = dcm_file;

            configSet('dcm_file',dcm_file);
        end

        % Callback function
        function createSubmodInfoButtonPushed(app, event)
            dcm_file = app.DcmFilePath.Value;
            if app.IncludeSubSystemCheckBox.Value
                SearchDepth = 'all';
            else
                SearchDepth = 1;
            end
%             SearchDepth = 'all';
%             ,'SearchDepth', SearchDepth)
            [portsInNames, portsOutNames, calibParams, infoText] = createSubmodInfo('DCMfileName',dcm_file,'SearchDepth', SearchDepth);
            app.LogTextArea.Value = infoText;
        end

        % Callback function
        function CreateBusGotoButtonPushed(app, event)
            createBusGoto()
            % 初始化logstr为空字符串
            logstr = sprintf('执行创建总线端Goto成功:\n');
            % 添加结果摘要
%             logstr = sprintf('%s  创建输入端口: %d\n', logstr, inCnt);
            app.LogTextArea.Value = logstr;
        end

        % Callback function
        function CreateBusPortButtonPushed(app, event)
            createBusPort()

            % 初始化logstr为空字符串
            logstr = sprintf('执行创建总线端口成功:\n');
            % 添加结果摘要
%             logstr = sprintf('%s  创建输入端口: %d\n', logstr, inCnt);
            app.LogTextArea.Value = logstr;
        end

        % Callback function
        function createCodePkgButtonPushed(app, event)
            SOFT_VERSION = app.SOFT_VERSIONEditField.Value;

            % save param
            % 加载现有配置
            existing_config = load('config.mat');
            existing_config.soft_version = SOFT_VERSION;
            % 保存更新后的配置
            save('config.mat', '-struct', 'existing_config');

            createCodePkg(app.VER_NAMEEditField.Value)

        end

        % Callback function
        function createIntegrationButtonPushed(app, event)
            % 加载现有配置
            existing_config = load('config.mat');

            STAGE = app.STAGEEditField.Value;
            INTERFACE_VERISON = app.INTERFACE_VERISONEditField.Value;
            DCM_NAME = app.DCM_NAMEEditField.Value;
            SOFT_VERSION = app.SOFT_VERSIONEditField.Value;
            VER_NAME = ['VcThermal_' STAGE '_' INTERFACE_VERISON '_' SOFT_VERSION];
            app.VER_NAMEEditField.Value = VER_NAME;

            % 不保存软件版本，回头等软件生成后整理代码的时候再保存
            existing_config.stage = STAGE;
            existing_config.vername = VER_NAME;
            existing_config.dcm_name = DCM_NAME;
            existing_config.interface_version = INTERFACE_VERISON;
            % 保存更新后的配置
            save('config.mat', '-struct', 'existing_config');

            createIntegration(SOFT_VERSION, 'STAGE',STAGE, 'INTERFACE_VERISON',INTERFACE_VERISON , 'DCM_NAME',DCM_NAME)
        end

        % Callback function
        function IntegrationTabButtonDown(app, event)
            app.LogTextArea.Value = '';
            existing_config = configGet();
            app.ActiveECUEditField.Value = existing_config.active_controller;
            com_used_model = existing_config.com_used_model;
            app.SubModDropDown.Items = com_used_model;

            soft_version_previous = existing_config.soft_version;
            % 期望提取SOFT_VERSION的前三位再和当前日期重新组成一个SOFT_VERSION
            current_date = datestr(now, 'mmdd');
            % 提取SOFT_VERSION的前三位并转换为数字
            soft_version_prefix = str2double(soft_version_previous(1:3));
            % 前缀数字加1
            soft_version_prefix = soft_version_prefix + 1;
            % 转回字符串格式
            soft_version_prefix = num2str(soft_version_prefix);
            % 重新组成新的SOFT_VERSION
            SOFT_VERSION = [soft_version_prefix, current_date];
            app.SOFT_VERSIONEditField.Value = SOFT_VERSION;

            STAGE = app.STAGEEditField.Value;
            INTERFACE_VERISON = app.INTERFACE_VERISONEditField.Value;
            VER_NAME = ['VcThermal_' STAGE '_' INTERFACE_VERISON '_' SOFT_VERSION];
            app.VER_NAMEEditField.Value = VER_NAME;

            logStr = sprintf('备注：\n');
            logStr = [logStr sprintf('函数功能简述如下，具体使用说明，请打开帮助手册（Help -> Sript Instrcution ，快捷键 Ctrl + h）\n')];
            logStr = [logStr sprintf('----------------------------------------------------------------------------------------------------------------------------------\n')];
            logStr = [logStr sprintf('ArxmlToModel: 将arxml 文件转换成autosar 框架模型\n')];
            logStr = [logStr sprintf('createIntegration：创建集成模型及所有相关配置，具体流程，详见Help -> PCMU Instrcution，快捷键 Ctrl + p\n')];
            logStr = [logStr sprintf('createCodePkg: 将生成好的代码进行文件提取并打包\n')];
            logStr = [logStr sprintf('SOFT_VERSION： 正常阶段不变的情况下，这个值不用改，小版本会根据之前的版本号自动递增，其后四位的日期也会被设置成当天日期\n')];
            logStr = [logStr sprintf('----------------------------------------------------------------------------------------------------------------------------------\n')];
            logStr = [logStr sprintf('CreateSubModel： 根据SubMod 参数，创建子模型的引用模型，并配置好相关参数\n')];
            logStr = [logStr sprintf('createCodeSubMod： 将生成好的代码进行文件提取并打包\n')];
            logStr = [logStr sprintf('changeCfgRef: 改变模型的引用配置，配置值为 RefConfig\n')];
            logStr = [logStr sprintf('changeCfgAutosar: 将模型配置成Autosar 配置\n')];
            logStr = [logStr sprintf('changeCfgErt： 将模型配置成ERT配置, 详见Help -> VCU Instrcution，快捷键 Ctrl + u\n')];

            app.LogTextArea.Value = logStr;

        end

        % Callback function
        function SubModDropDownValueChanged(app, event)
            value = app.SubModDropDown.Value;
            disp(value)
        end

        % Callback function
        function ControlChooseButtonGroupSelectionChanged(app, event)
            selectedButton = app.ControlChooseButtonGroup.SelectedObject;
            disp(selectedButton)
        end

        % Callback function
        function findSlddLoadPCMUButtonPushed(app, event)
            submod = app.SlddDropDown.Value;
            if(strcmp(submod,'bdroot'))
                submod = bdroot;
            end
            control_type = 'PCMU';
            findSlddLoad(submod,'mode',control_type)
%             findSlddLoad('TmSwArch','mode',control_type)
        end

        % Callback function
        function CreateRefModelButtonPushed(app, event)
            submod = app.SubModDropDown.Value;
            if strcmp(submod,'bdroot')
                submod = feval(submod);
            end
            control_type = app.ActiveECUEditField.Value;
            if strcmp(control_type,'PCMU')
                creatRefPCMU(submod)
            elseif strcmp(control_type,'VCU')
                creatRefVCU(submod)
            elseif strcmp(control_type,'XCU')
                creatRefXCU(submod)
            else
            end

        end

        % Callback function
        function LoadSlddAllButtonPushed(app, event)
            findSlddLoad('PCMU_SLDD_All.xlsx')
        end

        % Callback function
        function createCodeRefModButtonPushed(app, event)
            submod = app.SubModDropDown.Value;
            if strcmp(submod,'bdroot')
                submod = feval(submod);
            end
            
            control_type = app.ActiveECUEditField.Value;
            createCodeSubMod(submod, 'type', control_type)
        end

        % Callback function
        function createDebugButtonPushed(app, event)
            portsOutNames = createDebug(gcs);

            % 初始化logstr为空字符串
            logstr = '';

            % 添加结果摘要
            logstr = sprintf('%s创建完成: %d 个输入端口\n', ...
            logstr,length(portsOutNames));
            for i=1:length(portsOutNames)
                logstr = sprintf('%s%s\n',logstr,portsOutNames{i});
            end

            app.LogTextArea.Value = logstr;
        end

        % Callback function
        function createGotoBasedPortsButtonPushed(app, event)
            [numInputPorts, numOutputPorts] = createGotoBasedPorts(gcs);

            % 初始化logstr为空字符串
            logstr = '';

            % 添加结果摘要
            logstr = sprintf('%s创建完成: %d 个输入端口, %d 个输出端口\n', ...
            logstr,numInputPorts, numOutputPorts');

            
            app.LogTextArea.Value = logstr;
        end

        % Callback function
        function createSwitchCaseTabButtonDown(app, event)
            desc = {'0','SM0';
                '[1 21]','SM1SM21';
                '[2 22]','SM2SM22';
                '[3 23]','SM3SM23';
                '4','SM4';
                '5','SM5';
                '6','SM6';
                '7','SM7';
                };
            app.SwitchCaseTable.Data = desc;
        end

        % Callback function
        function AddButtonPushed(app, event)
            case_num = app.CaseListEditField.Value;
            case_name = app.CaseNameEditField.Value;
            data = app.SwitchCaseTable.Data;
            app.SwitchCaseTable.Data = [data;{case_num,case_name}];
        end

        % Callback function
        function RemoveButtonPushed(app, event)
            data = app.SwitchCaseTable.Data;
            data(end,:) = '';
            app.SwitchCaseTable.Data = data;
        end

        % Callback function
        function ClearAllButtonPushed(app, event)
            app.SwitchCaseTable.Data = '';
        end

        % Callback function
        function createSwitchCaseButtonPushed(app, event)
            caseIn = app.CaseInTextArea.Value;
            % 去掉caseIn中的空字符串行
            caseIn = caseIn(~cellfun(@isempty, caseIn));
            sigNames = app.SigNamesTextArea.Value;
            sigNames = sigNames(~cellfun(@isempty, sigNames))

            data = app.SwitchCaseTable.Data;
            caseList = strcat('{',strjoin(data(:,1),','),'}');
            caseNames = data(:,2);
            
            createSwitchCase( ...
                'caseIn',caseIn{1}, ...
                'caseList',caseList, ...
                'caseNames',caseNames, ...
                'sigNames',sigNames...
            );

            % 初始化logstr为空字符串
            logstr = sprintf('执行创建Switch Case成功:\n');
            % 添加结果摘要
%             logstr = sprintf('%s  创建输入端口: %d\n', logstr, inCnt);
            app.LogTextArea.Value = logstr;

        end

        % Callback function
        function createModeButtonPushed(app, event)
            inType = app.InTypeDropDown.Value;
            outType = app.OutTypeDropDown.Value;
            add = app.InsertBlockDropDown.Value;

            isEnableIn = strcmp(app.EnableInSwitch.Value,'On');
            isEnableOut = strcmp(app.EnableOutSwitch.Value,'On');
            resoveValue = strcmp(app.resoveValueSwitch.Value,'On');
            logValue = strcmp(app.logValueSwitch.Value,'On');
            testValue = strcmp(app.testValueSwitch.Value,'On');
            dispName = strcmp(app.dispNameSwitch.Value,'On');

            bkHalfLength = app.GotoHalfLengthSpinner.Value;

            createMode(gcb, ...
                'inType',inType, ...      % 可选参数 port, ground, from, const, none
                'outType',outType, ...       % 可选参数 port, term, goto, disp, none
                'suffixStr','',...
                ...                         % 创建 ports 相关配置
                'add', add,...           % 可选参数 blockType, None, etc：SignalConversion
                ...                         % 创建 goto from 相关配置
                'bkHalfLength', bkHalfLength,...
                ...                         % 创建 信号解析 相关配置
                'isEnableIn',isEnableIn,...
                'isEnableOut',isEnableOut,...
                'resoveValue',resoveValue,...
                'logValue',logValue,...
                'testValue',testValue,...
                'dispName', dispName...
                )
            % 初始化logstr为空字符串
%             logstr = '';

            % 添加结果摘要
            logstr = sprintf('创建模型端口成功\n');

            
            app.LogTextArea.Value = logstr;
        end

        % Callback function
        function createPortsButtonPushed(app, event)
            template_file = configGet('template_file');
            [inCnt, outCnt, inCntDel, outCntDel] = createPorts(template_file, gcs);
            % 初始化logstr为空字符串
            logstr = sprintf('执行成功:\n');

            % 添加结果摘要
            logstr = sprintf('%s  创建输入端口: %d\n', logstr, inCnt);
            logstr = sprintf('%s  创建输出端口: %d\n', logstr, outCnt); 
            logstr = sprintf('%s  删除输入端口: %d\n', logstr, inCntDel);
            logstr = sprintf('%s  删除输出端口: %d\n', logstr, outCntDel);

            app.LogTextArea.Value = logstr;
        end

        % Callback function
        function createPortsGotoButtonPushed(app, event)
            template_file = configGet('template_file');
            createRelated = app.CreateRelatedCheckBox.Value;
            gotoLength = app.gotoLengthSpinner.Value;
            [numInputPorts, numOutputPorts] = createPortsGoto( ...
                'fromTemplate', true, ...
                'template', template_file, ...
                'createRelated', createRelated, ...
                'gotoLength', gotoLength);
            % 初始化logstr为空字符串
            logstr = sprintf('执行成功:\n');

            % 添加结果摘要
            logstr = sprintf('%s  创建输入端口: %d\n', logstr, numInputPorts);
            logstr = sprintf('%s  创建输出端口: %d\n', logstr, numOutputPorts); 

            app.LogTextArea.Value = logstr;
        end

        % Callback function
        function CreatePortTabButtonDown(app, event)
            template_file = configGet('template_file');
            temp=readtable(template_file,'sheet','Signals');
            app.template.Data = temp(:,2:4);
%             
        end

        % Callback function
        function EditInTemplateButtonPushed(app, event)
            template_file = configGet('template_file');
            winopen(template_file);
        end

        % Callback function
        function ReloadButtonPushed(app, event)
            template_file = configGet('template_file');
            temp=readtable(template_file,'sheet','Signals');
            app.template.Data = temp(:,2:4);
            % 初始化logstr为空字符串
            logstr = sprintf('重载成功:\n');

            app.LogTextArea.Value = logstr;
        end

        % Callback function
        function createGotoUselessButtonPushed(app, event)
            [numGoto, numFrom] = createGotoUseless()

            % 初始化logstr为空字符串
            logstr = sprintf('执行成功:\n');

            % 添加结果摘要
            logstr = sprintf('%s  完成创建，共创建 %d 个Goto模块和 %d 个From模块\n',logstr, numGoto, numFrom);

            app.LogTextArea.Value = logstr;
        end

        % Callback function
        function createPortsGotoUpdateButtonPushed(app, event)
            nameOutMod = app.NameOutModeDropDown.Value;

            resoveValue = app.createPortsGotoUpdateResoveValueSwitch.Value;
            logValue = app.createPortsGotoUpdateLogValueSwitch.Value;
            testValue = app.createPortsGotoUpdateTestValueSwitch.Value;

            enableIn = app.createPortsGotoUpdateEnableInCheckBox.Value;
            enableOut = app.createPortsGotoUpdateEnableOutCheckBox.Value;

            resoveValue = strcmp(resoveValue,'On');
            logValue = strcmp(logValue,'On');
            testValue = strcmp(testValue,'On');

            [inList, outList] = createPortsGotoUpdate( ...
                'mode', nameOutMod, ...
                "resoveValue",resoveValue, ...
                "logValue",logValue, ...
                "testValue",testValue, ...
                "enableIn",enableIn, ...
                "enableOut",enableOut ...
                );

            % 初始化logstr为空字符串
            logstr = sprintf('执行成功:\n');

            % 添加结果摘要
            logstr = sprintf('%s  创建的输入信号如下：\n',logstr);
            logstr = sprintf('%s  %s\n',logstr, strjoin(inList,'\n  '));

            logstr = sprintf('%s  创建的输出信号如下：\n',logstr);
            logstr = sprintf('%s  %s\n',logstr, strjoin(outList,'\n  '));
            app.LogTextArea.Value = logstr;
        end

        % Callback function
        function createPortsGotoUpdateLoopButtonPushed(app, event)
            topLev = app.topLevEditField.Value;
            if strcmp(topLev,'bdroot')
                topLev = bdroot;
            end
            [inList, outList] = createPortsGotoUpdateLoop('topLev', topLev);


            % 初始化logstr为空字符串
            logstr = sprintf('执行成功:\n');

            % 添加结果摘要
            logstr = sprintf('%s  创建的输入信号如下：\n',logstr);
            logstr = sprintf('%s  %s\n',logstr, strjoin(inList,'\n  '));

            logstr = sprintf('%s  创建的输出信号如下：\n',logstr);
            logstr = sprintf('%s  %s\n',logstr, strjoin(outList,'\n  '));
            app.LogTextArea.Value = logstr;
        end

        % Callback function
        function createSubModButtonPushed(app, event)
            rows = app.SubModRowsSpinner.Value;
            FunPrefix = app.FunPrefixEditField.Value;
            ReqPrefix = app.ReqPrefixEditField.Value;
            template_file = configGet('template_file');

            modNums = createSubMod('template' ,template_file,'rows', rows, 'FunPrefix',FunPrefix, 'ReqPrefix',ReqPrefix);

            % 初始化logstr为空字符串
            logstr = '';

            % 添加结果摘要
            logstr = sprintf('%s创建完成: %d 个子模型\n', ...
            logstr,modNums);
            
            app.LogTextArea.Value = logstr;
        end

        % Callback function
        function creatIFInButtonPushed(app, event)
            numCreated = creatIFIn();
            % 初始化logstr为空字符串
            logstr = sprintf('执行接口输入模块成功:\n');

            % 添加结果摘要
            logstr = sprintf('%s  创建输入端口: %d\n', logstr, numCreated);

            app.LogTextArea.Value = logstr;
        end

        % Callback function
        function creatIFOutButtonPushed(app, event)
            numCreated = creatIFOut();
            % 初始化logstr为空字符串
            logstr = sprintf('执行接口输出模块成功:\n');

            % 添加结果摘要
            logstr = sprintf('%s  创建输出端口: %d\n', logstr, numCreated);

            app.LogTextArea.Value = logstr;
        end

        % Callback function
        function creatTmOutButtonPushed(app, event)
            numCreated = creatTmOut()
            % 初始化logstr为空字符串
            logstr = sprintf('执行热管理输出转换模块成功:\n');

            % 添加结果摘要
            logstr = sprintf('%s  创建输出端口: %d\n', logstr, numCreated);

            app.LogTextArea.Value = logstr;
        end

        % Callback function
        function creatTmBaseButtonPushed(app, event)
            creatTmBase()
        end

        % Callback function
        function createSlddButtonPushed(app, event)
            modeName = app.SlddDropDown.Value;
            if strcmp(modeName,'bdroot')
                modeName = bdroot;
            end
            createSldd(modeName)

            % 初始化logstr为空字符串
            logstr = sprintf('创建sldd成功:\n');

            app.LogTextArea.Value = logstr;
        end

        % Callback function
        function createSlddDesignDataButtonPushed(app, event)
            modeName = app.SlddDropDown.Value;
            if strcmp(modeName,'bdroot')
                modeName = bdroot;
            end
            createSlddDesignData(modeName)

            % 初始化logstr为空字符串
            logstr = sprintf('成功创建sldd 并将变量导入到sldd 的DesignData中:\n');

            app.LogTextArea.Value = logstr;
        end

        % Callback function
        function changePortBlockPosAllButtonPushed(app, event)
            changePortBlockPosAll(gcs)
        end

        % Callback function
        function changeArchSlddButtonPushed(app, event)
            app.LogTextArea.Value = '';
            sldd_file = 'PCMU_SLDD_All.xlsx';
            dcm_file = app.DcmFilePath.Value;
            if ~exist(sldd_file,'file')
                app.LoadAllSlddButtonPushed(event);
            else
                changeArchSldd(dcm_file, sldd_file)
            end
            
            % 初始化logstr为空字符串
            logstr = sprintf('执行成功:\n');
            app.LogTextArea.Value = logstr;
        end

        % Callback function
        function changeCfgAutosarButtonPushed(app, event)
            model = app.SubModDropDown.Value
            changeCfgAutosar(model)
        end

        % Callback function
        function changeCfgErtButtonPushed(app, event)
            model = app.SubModDropDown.Value
            changeCfgErt(model)   
        end

        % Callback function
        function changeCfgRefButtonPushed(app, event)
            refCfg = app.RefConfigEditField.Value
            model = app.SubModDropDown.Value
            changeCfgRef(model, refCfg)
        end

        % Callback function
        function changeModPortNameButtonPushed(app, event)
            oldName = app.OldNameEditField
            newName = app.NewNameEditField
            changeModPortName(gcb, oldName, newName)
        end

        % Callback function
        function changeModPortTypeButtonPushed(app, event)
            changeModPortType(gcs)
        end

        % Callback function
        function changeModSizeButtonPushed(app, event)
            wid = app.ModSizeDropDown.Value;
            changeModSize(gcb,'wid',str2double(wid))
        end

        % Callback function
        function changeModSizeGcsButtonPushed(app, event)
            rows = app.GcsArrangeRowsSpinner.Value
            changeModSizeGcs('rows', rows)
        end

        % Callback function
        function changeSigTypeButtonPushed(app, event)
            sig = app.SigNeedChgTypeEditField.Value
            changeSigType(sig)
        end

        % Callback function
        function OpenSlddFileButtonPushed(app, event)
            % select file through gui
            sldd_file = uigetfile('*.xlsx', 'Select SLDD File');
            if isequal(sldd_file, 0)
                % User cancelled the dialog box.
                return;
            end
            % 将app.SlddFilePath 的文本内容，设置成dcm_file
            app.SlddFilePath.Value = sldd_file;
        end

        % Callback function
        function changeSlddTableByInitValueButtonPushed(app, event)
            % select file through gui
            sldd_file = uigetfile('*.xlsx', 'Select SLDD File');
            if isequal(sldd_file, 0)
                % User cancelled the dialog box.
                return;
            end  
            changeSlddTableByInitValue(sldd_file)
        end

        % Callback function
        function changeSlddInitValueByTableButtonPushed(app, event)
            % select file through gui
            sldd_file = uigetfile('*.xlsx', 'Select SLDD File');
            if isequal(sldd_file, 0)
                % User cancelled the dialog box.
                return;
            end 
            changeSlddInitValueByTable(sldd_file)
        end

        % Callback function
        function findDCMNamesButtonPushed(app, event)
            % selectt file through gui
            dcm_file = uigetfile('*.dcm', 'Select DICOM File');
            if isequal(dcm_file, 0)
                % User cancelled the dialog box.
                return;
            end
            paramNames = findDCMNames(dcm_file);
            app.LogTextArea.Value = paramNames;
        end

        % Callback function
        function findDCMNamesChangesButtonPushed(app, event)
            dcm_file_first = uigetfile('*.dcm', 'Select first DCM File');
            if isequal(dcm_file_first, 0)
                % User cancelled the dialog box.
                return;
            end
            dcm_file_second= uigetfile('*.dcm', 'Select second DCM File');
            if isequal(dcm_file_second, 0)
                % User cancelled the dialog box.
                return;
            end

            diff = findDCMNamesChanges(dcm_file_first, dcm_file_second);


            added = diff.added;
            removed = diff.removed;
            common = diff.common;

            % 初始化logstr为空字符串
            logstr = '';

            % 添加结果摘要
            logstr = sprintf('%s比较结果摘要:\n', logstr);
            logstr = sprintf('%s  共有参数: %d\n', logstr, length(common));
            logstr = sprintf('%s  添加参数: %d\n', logstr, length(added));
            logstr = sprintf('%s  删除参数: %d\n', logstr, length(removed));
        
            % 添加具体的added和removed参数
            logstr = sprintf('%s------------------------------:\n', logstr);
            logstr = sprintf('%s具体变化信息:\n', logstr);
            if ~isempty(added)
                logstr = sprintf('%s  添加参数:\n', logstr);
                for i = 1:length(added)
                    logstr = sprintf('%s    %s\n', logstr, added{i});
                end
            end
            logstr = sprintf('%s-------------:\n', logstr);
            if ~isempty(removed)
                logstr = sprintf('%s  删除参数:\n', logstr); 
                for i = 1:length(removed)
                    logstr = sprintf('%s    %s\n', logstr, removed{i});
                end
            end
            logstr = sprintf('%s------------------------------', logstr);
            app.LogTextArea.Value = logstr;

        end

        % Callback function
        function findModPortsButtonPushed(app, event)
            getType = app.PortTypeDropDown.Value
            [name, PortsIn, PortsOut] = findModPorts(gcb, 'getType', getType);
            logstr = '';

            % 显示提示信息
            logstr = sprintf('%smodel name is:%s\n', logstr, name);
            logstr = sprintf('%s----------------------------------------:\n', logstr);
            logstr = sprintf('%s输入端口:\n', logstr);
            for i = 1:length(PortsIn)
                logstr = sprintf('%s    %s\n', logstr, PortsIn{i});
            end
            logstr = sprintf('%s-----------------------------:\n', logstr);
            logstr = sprintf('%s输出端口:\n', logstr);
            for i = 1:length(PortsOut)
                logstr = sprintf('%s    %s\n', logstr, PortsOut{i});
            end
            logstr = sprintf('%s----------------------------------------', logstr);

            app.LogTextArea.Value = logstr;
        end

        % Callback function
        function findParametersButtonPushed(app, event)
            path = app.AreaDropDown.Value;
            if strcmp(path,'gcs')
                path = gcs;
            elseif strcmp(path,'bdroot')
                path = bdroot;
            else
                disp('wrong path')
            end
            [PathAll, ParamAll] = findParameters(path);
            app.LogTextArea.Value = ParamAll;
        end

        % Callback function
        function findSlddButtonPushed(app, event)
            app.findSlddButton.UserData = '';
            
            path = app.AreaDropDown.Value;
            if strcmp(path,'gcs')
                path = gcs;
            elseif strcmp(path,'bdroot')
                path = bdroot;
            else
                disp('wrong path')
            end
            overWrite = app.SlddOverwriteCheckBox.Value;
            project = app.MCUTypeDropDown.Value;
            [fSlddList, DataList] = findSldd(path,'overWrite',overWrite,'projectList', {project});
            logstr = sprintf('The exported sldd are as follows:\n');
            logstr = sprintf('%s the % sldd path is: %s\n', logstr, project,fSlddList{1});
            app.LogTextArea.Value = logstr;          
            rstPath.PCMU = fSlddList;
            app.findSlddButton.UserData = rstPath;
        end

        % Callback function
        function HomeTabButtonDown(app, event)
            logStr = '';
            logStr = [logStr sprintf('Welcome to use smart thermal app!：\n')];
            logStr = [logStr sprintf('具体使用说明，请打开帮助手册（Help -> Sript Instrcution ，快捷键 Ctrl + h）\n')];
            logStr = [logStr sprintf('----------------------------------------------------------------------------------------------------------------------------------\n')];

            app.LogTextArea.Value = logStr;    
        end

        % Callback function
        function delUselessLineButtonPushed(app, event)
            delUselessLine(bdroot)
        end

        % Callback function
        function delUselessPortButtonPushed(app, event)
            UselessInBlock = app.UselessInBlockDropDown.Value;
            UselessOutBlock = app.UselessOutBlockDropDown.Value;
            delUselessPort(gcs,'UselessInBlock', UselessInBlock, 'UselessOutBlock', UselessOutBlock);
        end

        % Callback function
        function delGcsAllButtonPushed(app, event)
            delGcsAll()
        end

        % Selection changed function: CaseListTree
        function AboutMenuSelected(app, event)
            % 点击About 菜单，弹出框显示一些文字信息：
            % 软件版本：V1.0
            % 发布如期：2025.5.7
            % 维护人员：Blue.ge @ Smart Therm.Dept.
            msgbox({'软件版本：V1.0','发布如期：2025.5.7','维护人员：Blue.ge @ Smart Therm.Dept.'});
        end

        % Callback function
        function OpenTemplateClicked(app, event)
            % 加载默认配置文件
            template_file = configGet('template_file');
            if isempty(template_file)
                return
            end
            winopen(template_file);
        end

        % Callback function
        function ArxmlToModelButtonPushed(app, event)
            Arxmlstart2mdl
        end

        % Callback function
        function ScriptInstructionMenuSelected(app, event)

            % 加载默认配置文件
            template_file = configGet('template_file');
            if isempty(template_file)
                return
            end
            winopen('Script Instruction.pdf')
            
        end

        % Button pushed function: AddCaseButton
        function AddCaseButtonPushed(app, event)
            caseList = app.TestCaseTextArea.Value;
            SelectedNode = app.CaseListTree.SelectedNodes;

            if isempty(SelectedNode)
                % 如果没有选中节点，添加到根节点下
                for i = 1:length(caseList)
                    uitreenode(app.CaseListTree, 'Text', caseList{i});
                end
            else
                % 添加到选中节点下
                for i = 1:length(caseList)
                    uitreenode(SelectedNode, 'Text', caseList{i});
                end
            end
        end

        % Callback function
        function DelCaseButtonPushed(app, event)
            SelectedNode = app.CaseListTree.SelectedNodes;
            delete(SelectedNode)
        end

        % Button pushed function: GetPortsButton
        function GetPortsButtonPushed(app, event)

%获取当前选中模型名称、输入、输出属性；
%%Mod_Name/Mod_PortsIn/Mod_PortsOut当前模型的名称、输入、输出；
            [Mod_Name,Mod_PortsIn,Mod_PortsOut] = findModPorts(gcb, 'getType', 'Name');
            params_Loc =  load('harnessfromtable.mat');
            harnessDataArrayfromtable_Loc = params_Loc.harnessDataArrayfromtable;
%数据初始化
            data_Loc='';
            LEV1_Loc='';
            LEV2_Loc='';
            PortsIn_Loc='';
            PortsOut_Loc='';
%%节点二查漏补缺
            % 获取树形结构数据
            rootNodes = app.CaseListTree.Children;
            for i=1:length(rootNodes)
                lev1Node = rootNodes(i);
                if ~isempty(lev1Node.Children)
                    %doNothing
                else
                    uitreenode(lev1Node,'Text',lev1Node.Text);
                end
            end
%%标题栏
            Head = {'Lev1','Lev2'};
            HoldTime={'HoldTime'};%%hjm,新增等待时间
%确认该模型是否曾存在数据库中
%%data_Loc/LEV1_Loc/LEV2_Loc/PortsIn_Loc/PortsOut_Loc数据库中模型相关参数
            GetPortsNew=0;
            for i=1:length(harnessDataArrayfromtable_Loc)
                data_Loc  = harnessDataArrayfromtable_Loc(i);
                if strcmp(data_Loc.modelName,Mod_Name)
                    GetPortsNew=1;
                    LEV1_Loc = data_Loc.lev1;
                    LEV2_Loc = data_Loc.lev2;
                    PortsIn_Loc = data_Loc.PortsIn;
                    PortsOut_Loc = data_Loc.PortsOut;
                    break;
                end
            end
%%确认模型是否变更接口
            if(GetPortsNew==1)
                Size_New1=length(Mod_PortsIn);
                Size_Old1=length(PortsIn_Loc);
                Size_New2=length(Mod_PortsOut);
                Size_Old2=length(PortsOut_Loc);
                if (Size_New1==Size_Old1)&&(Size_New2==Size_Old2)
                    %%数量先进行比较
                    for i=1:Size_New1
                        %%输入信号逐一比较
                        if ~strcmp(Mod_PortsIn(i),PortsIn_Loc(i))
                            GetPortsNew=0;%存在输入变化，选择覆盖原用例
                            break;
                        end
                    end
                    for i=1:Size_New2
                        %%输出信号逐一比较
                        if ~strcmp(Mod_PortsOut(i),PortsOut_Loc(i))
                            GetPortsNew=0;%存在输出变化，选择覆盖原用例
                            break;
                        end
                    end
                else
                    GetPortsNew=0;%%数量不一致，选择覆盖原用例
                end
            end

%%判断当前用例处理方式，覆盖还是重新调整           
            if(GetPortsNew==1)
                fig = uifigure;
                msg = '已存在该模型测试用例！';
                title = '是否覆盖';
                selection = uiconfirm(fig,msg,title, ...
                     'Options',{'Overwrite','ShowCase','Modify','Cancel'}, ...
                      'DefaultOption',3,'CancelOption',3);
                switch selection
                    case 'Overwrite'
                        GetPortsNew=0;
                    case 'ShowCase'
                        GetPortsNew=2;
                    case 'Modify'
                        GetPortsNew=3;
                    case 'Cancel'
                        return
                end
            end  

%%GetPortsNew==3,在原有的基础上修改
            if (GetPortsNew==3)
                %表头设置
                Head = [Head HoldTime Mod_PortsIn Mod_PortsOut];%%hjm,新增等待时间
                app.CaseTable.ColumnName = Head;
                app.CaseTable.Data = cell(1, numel(Head));  % 创建 1 行 5 列的空 cell 数组
                totalRows = 0;
                %%数据库中数据提取
                for i=1:length(LEV1_Loc)
                    for j=1:length(LEV2_Loc{i})
                        totalRows = totalRows+1;
                        app.CaseTable.Data{totalRows,1} = LEV1_Loc{i};
                        app.CaseTable.Data{totalRows,2} = LEV2_Loc{i}{j};
                        app.CaseTable.Data(totalRows,3)=data_Loc.HoldTime{i}{j};% 填充时间
                        app.CaseTable.Data(totalRows,4:(length(PortsIn_Loc)+3)) = data_Loc.inValue{i}{j};% 填充输入值
                        app.CaseTable.Data(totalRows,length(PortsIn_Loc)+4:end) = data_Loc.rstValue{i}{j};% 填充输入值
                    end
                end
                % 初始化dataValue矩阵
                dataValue = app.CaseTable.Data;
                NewArray = cell(1,3+length(PortsIn_Loc) + length(PortsOut_Loc));
                NewValue = cell(1,3+length(PortsIn_Loc) + length(PortsOut_Loc));
                %当前tree比较
                rootNode = app.CaseListTree.Children;
                treeRows = 0;
                for i = 1:length(rootNode)
                    lev1Node = rootNode(i);
                    for j=1:length(lev1Node.Children)
                        lev2Node = lev1Node.Children(j);
                        treeRows=treeRows+1;
                        NewValue{treeRows,1}=lev1Node.Text;
                        NewValue{treeRows,2}=lev2Node.Text;
                        NewValue=[NewValue;NewArray];
                    end
                end
                NewValue=NewValue(1:treeRows,:);
                levMatch = 1;
                row = 1;
                %%找到对应row
                for i=1:treeRows
                    while ~(strcmp(NewValue{i,1},dataValue{min(row,totalRows),1})&&strcmp(NewValue{i,2},...
                            dataValue{min(row,totalRows),2}))&&(row<=totalRows)
                        row=row+1;
                    end
                    if row>totalRows
                        levMatch=0;
                    else
                        levMatch=row;
                    end
                    if levMatch~=0
                        NewValue(i,:)=dataValue(levMatch,:);
                    end
                end
                %%整理当前tree测试用例
                app.CaseTable.Data=NewValue;
            end


%%GetPortsNew==0,完全重新建立用例
            if (GetPortsNew==0)
            Head = {'Lev1','Lev2'};
            HoldTime={'HoldTime'};%%hjm,新增等待时间
            Head = [Head HoldTime Mod_PortsIn Mod_PortsOut];%%hjm,新增等待时间
            app.CaseTable.ColumnName = Head;
            app.CaseTable.Data = cell(1, numel(Head));  % 创建 1 行 5 列的空 cell 数组


            % 获取树形结构中的所有节点
            rootNode = app.CaseListTree.Children;
            row = 1;
            
            % 遍历第一层节点
            for i = 1:length(rootNode)
                lev1Node = rootNode(i);
%                 % 填充第一层节点数据
                app.CaseTable.Data{row,1} = lev1Node.Text;
%                 
                % 遍历第二层节点
                if ~isempty(lev1Node.Children)
                    for j = 1:length(lev1Node.Children)
                        lev2Node = lev1Node.Children(j);
                        % 填充第二层节点数据
                        app.CaseTable.Data{row,1} = lev1Node.Text;
                        app.CaseTable.Data{row,2} = lev2Node.Text;

                        row = row + 1;
                    end
                else
                    row = row + 1;
                end
            end
            %%填入信息
            end


%%GetPortsNew==2,展示旧case
            if (GetPortsNew==2)
                app.CaseTable.Data = cell(0,0);
%                 logstr = sprintf('准备加载模型%s的测试参数\n', value);
%                 app.LogTextArea.Value = logstr;
           
            %% 填充输入输出信号
            HoldTime={'HoldTime'};%%hjm,新增等待时间
            PortsIn = data_Loc.PortsIn;
            PortsOut = data_Loc.PortsOut;
            Head = ['Lev1','Lev2' HoldTime PortsIn PortsOut];
            app.CaseTable.ColumnName = Head;


            %% 填充测试用例
            LEV1 = data_Loc.lev1
            LEV2 = data_Loc.lev2
            % 清空uitreenode
            app.CaseListTree.Children.delete;
            for i = 1:length(LEV1)
                node = uitreenode(app.CaseListTree, 'Text', LEV1{i});
                for j = 1:length(LEV2{i})
                    uitreenode(node, 'Text', LEV2{i}{j});
                end
            end

            %% 填充测试数据
            % 获取树形结构中的所有节点，并填充测试用例区域
            rootNode = app.CaseListTree.Children;
            row = 1;
            
            % 遍历第一层节点
            for i = 1:length(rootNode)
                lev1Node = rootNode(i);
                % 遍历第二层节点
                if ~isempty(lev1Node.Children)
                    for j = 1:length(lev1Node.Children)
                        lev2Node = lev1Node.Children(j);
                        % 填充第一层节点数据  
                        app.CaseTable.Data{row,1} = lev1Node.Text;
                        % 填充第二层节点数据
                        app.CaseTable.Data{row,2} = lev2Node.Text;
                        row = row + 1;
                    end
                else
                    row = row + 1;
                end
            end

            % 获取数据区域中的data.inValue 和 data.rstValue, 并填充数据区域
            % 计算总行数
            totalRows = 0;
            for i = 1:length(data_Loc.lev2)
                totalRows = totalRows + length(data_Loc.lev2{i});
            end
            
            % 初始化dataValue矩阵
            dataValue = cell(totalRows, 1+length(PortsIn) + length(PortsOut));
            
            % 填充inValue和rstValue
            row = 1;
            for i = 1:length(data_Loc.inValue)
                for j = 1:length(data_Loc.inValue{i})
                    % 填充时间
                    dataValue(row,1)=data_Loc.HoldTime{i}{j};
                    % 填充输入值
                    dataValue(row, 2:(length(PortsIn)+1)) = data_Loc.inValue{i}{j};
                    % 填充输出值
                    dataValue(row, length(PortsIn)+2:end) = data_Loc.rstValue{i}{j}(1:length(PortsOut));
                    row = row + 1;
                end
            end
            
            % 将合并后的数据填充到表格中
            app.CaseTable.Data(1:totalRows, 3:3+length(PortsIn) + length(PortsOut)) = dataValue;

            end
     
            % 设置输出端口列的背景色为绿色
            app.CaseTable.BackgroundColor = [1 1 1; 0.9 1 0.9]; % 设置输出端口列的背景色为浅绿色

        end

        % Button pushed function: createHarnessButton
        function createHarnessButtonPushed(app, event)

             load('harnessfromtable.mat');


            % 获取下拉框的值
            lastStep = app.LastStepDropDown.Value;
            nextStep = app.NextStepDropDown.Value;
            
            % 获取树形结构数据
            rootNodes = app.CaseListTree.Children;
            lev1 = {rootNodes.Text};
            
            % 构建lev2数据结构
            lev2 = cell(1,length(rootNodes));
            for i = 1:length(rootNodes)
                if ~isempty(rootNodes(i).Children)
                    lev2{i} = {rootNodes(i).Children.Text};
                else
                    lev2{i} = {};
                end
            end
            
            % 获取端口信息
            [modelName,PortsIn,PortsOut] = findModPorts(gcb, 'getType', 'Name');
            
            caseCls = length(lev1);
            inName=cell(1,caseCls);
            rstName=cell(1,caseCls);
            for i=1:caseCls
                inName{i} = PortsIn;
            end
            for i=1:caseCls
                rstName{i} = PortsOut;
            end
            
            % 从表格中获取输入值
            tableData = app.CaseTable.Data;
            inValue = cell(1,length(lev1));
            for i = 1:length(lev1)
                inValue{i} = cell(1,length(lev2{i}));
                for j = 1:length(lev2{i})
                    rowIdx = find(strcmp(tableData(:,1), lev1{i}) & strcmp(tableData(:,2), lev2{i}{j}));
                    if ~isempty(rowIdx)
                        inValue{i}{j} = tableData(rowIdx,4:3+length(PortsIn));%%hjm,3改成4，去除holdtime
                    end
                end
            end
            
            % 从表格中获取输出值
            rstValue = cell(1,length(lev1));
            for i = 1:length(lev1)
                rstValue{i} = cell(1,length(lev2{i}));
                for j = 1:length(lev2{i})
                    rowIdx = find(strcmp(tableData(:,1), lev1{i}) & strcmp(tableData(:,2), lev2{i}{j}));
                    if ~isempty(rowIdx)
                        rstValue{i}{j} = tableData(rowIdx,4+length(PortsIn):end);%%hjm,3改成4，去除holdtime
                    end
                end
            end

            %hjm，从表格中获取HoldTime值
            HoldTime = cell(1,length(lev1));
            for i = 1:length(lev1)
                HoldTime{i}=cell(1,length(lev2{i}));
                for j = 1:length(lev2{i})
                    rowIdx = find(strcmp(tableData(:,1), lev1{i}) & strcmp(tableData(:,2), lev2{i}{j}));
                    if ~isempty(rowIdx)
                        HoldTime{i}{j} =  tableData(rowIdx,3);
                    end
                end
            end


            % 创建结构体保存所有变量%%hjm,新增HoldTime
            saveHarnessParamsfromtable('modelName', modelName, ...
            'PortsIn', PortsIn, ...
            'PortsOut', PortsOut, ...
            'lastStep', lastStep, ...
            'nextStep', nextStep, ...
            'lev1', lev1, ...
            'lev2', lev2, ...
            'HoldTime',HoldTime,...
            'inName', inName, ...
            'rstName', rstName, ...
            'inValue', inValue, ...
            'rstValue', rstValue,...
            'harnessDataArrayfromtable',harnessDataArrayfromtable);

            createHarnessfromtable(gcb, ...
                'lastStep',lastStep,...  % Initialize
                'nextStep',nextStep,...  % Initialize
                'lev1',lev1, ...
                'lev2',lev2, ...
                'inName',inName,...
                'rstName',rstName,...
                'inValue',inValue,...
                'rstValue',rstValue,...
                'HoldTime',HoldTime...
                )
        end

        % Callback function
        function DemoButtonPushed(app, event)
            [ModelName,PortsIn,PortsOut] = findModPorts(gcb, 'getType', 'Name');
            Head = {'Lev1','Lev2'};
            Head = [Head PortsIn PortsOut]
            cols = length(Head);
            app.CaseTable.ColumnName = Head;


            LEV1 = {'NHP','HP'};
            LEV2 = { ...
                        {'NoLimit','Limit'},...
                        {'NoLimit','Limit','Limit2','Limit3'},...
                    };
            cases = {
                'NHP','NoLimit';
                'NHP','Limit';
                'HP','NoLimit';
                'HP','Limit';
                'HP','Limit2';
                'HP','Limit3';
            };

%             data={
%                 '0','0','0','1','0','8000','false';
%                 '0','0','0','1','[1 2 3]','3000','true';
%                 '0','0','0','2','0','8000','false';
%                 '0','1','0','2','0','3000','true';
%                 '0','0','1','2','0','3000','true';
%                 '1','0','0','2','0','3000','true'
%                 };

            data={
                '0','0','0','1','0','8000','0';
                '0','0','0','1','[1 2 3]','3000','1';
                '0','0','0','2','0','8000','0';
                '0','1','0','2','0','3000','1';
                '0','0','1','2','0','3000','1';
                '1','0','0','2','0','3000','1'
                };

            % 如果没有选中节点，添加到根节点下
            app.CaseListTree.Children.delete
            rows=0;
            for i = 1:length(LEV1)
                node = uitreenode(app.CaseListTree, 'Text', LEV1{i});
                for j = 1:length(LEV2{i})
                    uitreenode(node, 'Text', LEV2{i}{j});
                    rows = rows + 1;
                end
            end

            % 初始化空数据
            app.CaseTable.Data = cell(rows,cols);

            app.CaseTable.Data(1:6,1:2) = cases;
            app.CaseTable.Data(1:6,3:9) = data;
        end

        % Value changed function: HarnessParamsListBox
        function HarnessParamsListBoxValueChanged(app, event)
            % 初始化空数据
            app.CaseTable.Data = cell(0,0);
            
            value = app.HarnessParamsListBox.Value;

            logstr = sprintf('准备加载模型%s的测试参数\n', value);

            app.LogTextArea.Value = logstr;

            params =  load('harnessfromtable.mat');
            harnessDataArrayfromtable = params.harnessDataArrayfromtable;
            data='';
            for i=1:length(harnessDataArrayfromtable)
                data  = harnessDataArrayfromtable(i);
                if strcmp(data.modelName,value)
                    break;
                end
            end

            
            %% 填充输入输出信号
            PortsIn = data.PortsIn;
            PortsOut = data.PortsOut;
            HoldTime={'HoldTime'};%%hjm,新增等待时间
            Head = ['Lev1','Lev2' HoldTime PortsIn PortsOut];
            app.CaseTable.ColumnName = Head;


            %% 填充测试用例
            LEV1 = data.lev1
            LEV2 = data.lev2
            % 清空uitreenode
            app.CaseListTree.Children.delete;
            for i = 1:length(LEV1)
                node = uitreenode(app.CaseListTree, 'Text', LEV1{i});
                for j = 1:length(LEV2{i})
                    uitreenode(node, 'Text', LEV2{i}{j});
                end
            end

            %% 填充测试数据
            % 获取树形结构中的所有节点，并填充测试用例区域
            rootNode = app.CaseListTree.Children;
            row = 1;
            
            % 遍历第一层节点
            for i = 1:length(rootNode)
                lev1Node = rootNode(i);
                % 遍历第二层节点
                if ~isempty(lev1Node.Children)
                    for j = 1:length(lev1Node.Children)
                        lev2Node = lev1Node.Children(j);
                        % 填充第一层节点数据  
                        app.CaseTable.Data{row,1} = lev1Node.Text;
                        % 填充第二层节点数据
                        app.CaseTable.Data{row,2} = lev2Node.Text;
                        row = row + 1;
                    end
                else
                    row = row + 1;
                end
            end

            % 获取数据区域中的data.inValue 和 data.rstValue, 并填充数据区域
            % 计算总行数
            totalRows = 0;
            for i = 1:length(data.lev2)
                totalRows = totalRows + length(data.lev2{i});
            end
            
            % 初始化dataValue矩阵
            dataValue = cell(totalRows, 1+length(PortsIn) + length(PortsOut));
            
            % 填充inValue和rstValue
            row = 1;
            for i = 1:length(data.inValue)
                for j = 1:length(data.inValue{i})
                    %填充等待时间
                    dataValue(row,1)=data.HoldTime{i}{j};
                    % 填充输入值
                    dataValue(row, 2:(length(PortsIn)+1)) = data.inValue{i}{j};
                    % 填充输出值
                    dataValue(row, length(PortsIn)+2:end) = data.rstValue{i}{j}(1:length(PortsOut));
                    row = row + 1;
                end
            end
            
            % 将合并后的数据填充到表格中
            app.CaseTable.Data(1:totalRows, 3:3+length(PortsIn) + length(PortsOut)) = dataValue;

        end

        % Button down function: TestTab
        function TestTabButtonDown(app, event)
            app.LogTextArea.Value = '';
            if ~exist('harnessfromtable.mat', 'file')
                return
            end
            load('harnessfromtable.mat')
            len = length(harnessDataArrayfromtable);
            list = cell(1,len);
            for i=1:len
                list{i} = harnessDataArrayfromtable(i).modelName;
            end
            app.HarnessParamsListBox.Items = list;

            logStr = '';
            logStr = [logStr sprintf('备注：\n')];
            logStr = [logStr sprintf('函数功能简述如下，具体使用说明，请打开帮助手册（Help -> Sript Instrcution ，快捷键 Ctrl + h）\n')];
            logStr = [logStr sprintf('----------------------------------------------------------------------------------------------------------------------------------\n')];
            logStr = [logStr sprintf('AddCase: 将TestCase中的内容添加到根目录CaseTree中，作为当前harness的测试大类， 如果需要添加子类，需要先选中大类，再点此按键\n')];
            logStr = [logStr sprintf('DelCase：将CaseTree中的测试用例删除\n')];
            logStr = [logStr sprintf('GetPorts: 点击子模型，生成对应的输入输出信号，同时根据CaseTree, 生成测试用例个数，然后就可以对其进行填充\n')];
            logStr = [logStr sprintf('createHarness： 根据测试用例，生成harness\n')];
            logStr = [logStr sprintf('Demo: 快速生成demo CaseTree, 需要打开并点击模型：ScriptDemo/createHarness/Demo\n')];
            logStr = [logStr sprintf('DelParam: 删除Harness Histroy Params 选中的参数\n')];
            logStr = [logStr sprintf('在表格上单击右键，可以快速删除全部数据或者选中数据\n')];

            app.LogTextArea.Value = logStr;

        end

        % Clicked callback: HarnessParamsListBox
        function HarnessParamsListBoxClicked(app, event)
            item = event.InteractionInformation.Item
            
        end

        % Callback function
        function DelParamButtonPushed(app, event)
            item = app.HarnessParamsListBox.Value;
            if isempty(item)
                app.LogTextArea.Value = '未选中相关参数';
                return
            end

            % 删除数据库中的参数
            load('harnessfromtable.mat')
            len = length(harnessDataArrayfromtable);
            for i=1:len
                if strcmp(harnessDataArrayfromtable(i).modelName,item)
                    % 删除这个配置项并重新保存
                    harnessDataArrayfromtable(i) = [];  % 删除该元素
                    save('harnessfromtable.mat', 'harnessDataArrayfromtable');  % 保存更新后的数据
                    break
                end
            end

            % 删除这个列表项中的item
            items = app.HarnessParamsListBox.Items;
            idx = find(strcmp(items, item));
            if ~isempty(idx)
                items(idx) = [];  % 从列表中删除该项
                app.HarnessParamsListBox.Items = items;  % 更新列表框
            end
        end

        % Callback function
        function DelDataAllButtonPushed(app, event)
            % 将table 中的数据，全部置为空字符串
            app.CaseTable.Data = cell(size(app.CaseTable.Data));
        end

        % Callback function
        function DelDataSelectButtonPushed(app, event)
            % 删除CaseTable中鼠标选中的数据
            if isempty(app.CaseTable.Selected)
                app.LogTextArea.Value = '未选中任何数据';
                return;
            end
            app.CaseTable.Data(app.CaseTable.Selected,:) = [];
            app.LogTextArea.Value = '已删除选中的数据';
        end

        % Menu selected function: DelSelectMenu
        function DelSelectMenuSelected(app, event)
            % 删除CaseTable中鼠标选中的数据
            selection = app.CaseTable.Selection;
            
            % 检查是否有选中的数据
            if isempty(selection)
                app.LogTextArea.Value = '未选中任何数据';
                return;
            end
            
            % 获取当前表格数据
            currentData = app.CaseTable.Data;
            
            % 将选中位置的数据置为空字符串
            for i = 1:size(selection, 1)
                row = selection(i, 1);
                col = selection(i, 2);
                currentData{row, col} = '';
            end
            
            % 更新表格数据
            app.CaseTable.Data = currentData;
            app.LogTextArea.Value = '已清空选中的数据';
        end

        % Menu selected function: DelAllMenu
        function DelAllMenuSelected(app, event)
            % 将table 中的数据，全部置为空字符串
            app.CaseTable.Data = cell(size(app.CaseTable.Data));
        end

        % Callback function
        function CheckTabButtonDown(app, event)
            logstr =sprintf('一般需要检查点如下：\n');

            % 显示提示信息
            logstr = sprintf('%s1. 删除无用的连线\n', logstr);
            logstr = sprintf('%s1. 确保所有的goto, from 都有配对\n', logstr);
            logstr = sprintf('%s2. 确保一维查表及二维查表，中间是线性插值，两端是截至clip\n', logstr);
            logstr = sprintf('%s3. 确保除数模块的被除数不为0\n', logstr);
            logstr = sprintf('%s4. 确保无符号整数的减法不会溢出\n', logstr);
            logstr = sprintf('%s5. 确保模型无代数环\n', logstr);

            app.LogTextArea.Value = logstr;
        end

        % Callback function
        function findSlddLoadVCUButtonPushed(app, event)
            submod = app.SlddDropDown.Value;
            control_type = 'VCU';
            findSlddLoad(submod,'mode',control_type)
            findSlddLoad('TmSwArch','mode',control_type)
        end

        % Callback function
        function SlddTabButtonDown(app, event)
            com_used_model = configGet().com_used_model;
            app.SlddDropDown.Items = com_used_model;
            app.LogTextArea.Value = '';
        end

        % Callback function
        function openSlddButtonPushed(app, event)

            slddType = app.MCUTypeDropDown.Value;
            overwrite = app.SlddOverwriteCheckBox.Value;

%             rstPath = app.findSlddButton.UserData;
%             if isempty(rstPath)
%                 return
%             end
%             if strcmp(slddType, 'PCMU')
%                 slddName = rstPath.PCMU;
%             else
%                 slddName = rstPath.VCU;
%             end

            if overwrite
                slddName = [bdroot '_DD_' slddType '.xlsx'];
            else
                slddName = [bdroot '_DD_' slddType '_EXPORT.xlsx'];
            end
%           
            logStr = sprintf('can not find the file: %s ', slddName);
            if ~exist(slddName,'file')
                app.LogTextArea.Value = logStr;
                return
            end

            winopen(slddName)
        end

        % Callback function
        function changeExportedSlddButtonPushed(app, event)
            slddType = app.SlddTypeButtonGroup.SelectedObject.Text;

            %             slddName = [bdroot '_DD_' slddType '_EXPORT.xlsx'];

            rstPath = app.findSlddButton.UserData;
            if isempty(rstPath)
                return
            end
            
            if strcmp(slddType, 'PCMU')
                slddName = rstPath.PCMU;
            else
                slddName = rstPath.VCU;
            end

            dcm_file = app.DcmFilePath.Value;
            changeArchSldd(dcm_file, slddName);
        end

        % Callback function
        function LoadAllSlddButtonPushed(app, event)
            SLDD_NAME = 'PCMU_SLDD_All.xlsx';
            DCM_NAME = app.DcmFilePath.Value;
            if ~exist('PCMU_SLDD_All.xlsx','file')
                app.LogTextArea.Value = sprintf('file of %s not existed. will create first\n',SLDD_NAME);

                proj = currentProject; 
                rootPath = proj.RootFolder;
                subPath = fullfile(rootPath,'SubModel');
                if ~exist(subPath, 'dir')
                    error('wrong sub model path')
                end
                
                findSlddCombine(subPath, SLDD_NAME);
            end
            changeArchSldd(DCM_NAME, SLDD_NAME);
            findSlddLoad(SLDD_NAME)
        end

        % Callback function
        function ModelTabButtonDown(app, event)
            logStr = '';
            logStr = [logStr sprintf('备注：\n')];
            logStr = [logStr sprintf('具体使用说明，请打开帮助手册（Help -> Sript Instrcution ，快捷键 Ctrl + h）\n')];
            logStr = [logStr sprintf('----------------------------------------------------------------------------------------------------------------------------------\n')];

            app.LogTextArea.Value = logStr;    
        end

        % Callback function
        function findDCMValuesChangesButtonPushed(app, event)
            [name, path] = uigetfile('*.dcm', 'Select old DCM File');
            dcm_file_old = fullfile(path,name);
            if isequal(dcm_file_old, 0)
                % User cancelled the dialog box.
                return;
            end
            [name, path] = uigetfile('*.dcm', 'Select new DCM File');
            dcm_file_new = fullfile(path,name);
            if isequal(dcm_file_new, 0)
                % User cancelled the dialog box.
                return;
            end

            changes = findDCMValuesChanges(dcm_file_old, dcm_file_new);


            %% 显示比较结果
            % 参数类型名称映射
            typeNames = {'常量', '轴定义', '值块', '一维表', '二维表'};
            logstr = sprintf('    %s    \n和\n    %s    \n的参数差异如下：\n\n', dcm_file_old,dcm_file_new);
            if isempty(changes)
                logstr = sprintf('%s未发现参数值的变化。\n', logstr);
            else
                logstr = sprintf('%s发现 %d 个参数值有变化:\n', logstr, length(changes));
                
                logstr = sprintf('%s--------------------------------------------------------------\n', logstr);
                logstr = sprintf('%s%-30s %-15s %-30s\n', logstr, '参数名称', '类型', '变化描述');
                logstr = sprintf('%s--------------------------------------------------------------\n', logstr);
                
                for i = 1:length(changes)
                    change = changes(i);
                    typeName = typeNames{change.type};
                    
                    % 根据参数类型格式化变化描述
                    switch change.type
                        case 1 % 常量
                            if ischar(change.diff)
                                diffDesc = change.diff;
                            else
                                diffDesc = sprintf('%.6g -> %.6g (变化: %.6g)', ...
                                            change.oldValue, change.newValue, change.diff);
                            end
                            
                        case {2, 3, 4, 5} % 轴定义、值块、一维表或二维表
                            diffDesc = change.diff;
                    end
                    
                    logstr = sprintf('%s%-30s %-15s %-30s\n', logstr, change.name, typeName, diffDesc);
                end
                logstr = sprintf('%s--------------------------------------------------------------\n', logstr);

            end
            app.LogTextArea.Value = logstr;
        end

        % Callback function
        function PCMUInstructionMenuSelected(app, event)
            winopen('PCMU Instruction.pdf')
        end

        % Callback function
        function VCUInstructionMenuSelected(app, event)
            winopen('HY11 VCU1自研软件集成指南-陈远飞20240130_V3.pdf')
        end

        % Button down function: TabGroup
        function TabGroupButtonDown(app, event)
            
        end

        % Callback function
        function OpenRelatedDCMButtonPushed(app, event)
            dcm_file = app.DcmFilePath.Value;
            winopen(dcm_file)
        end

        % Menu selected function: DelParamMenu
        function DelParamMenuSelected(app, event)
            item = app.HarnessParamsListBox.Value;
            if isempty(item)
                app.LogTextArea.Value = '未选中相关参数';
                return
            end

            % 删除数据库中的参数
            load('harnessfromtable.mat')
            len = length(harnessDataArrayfromtable);
            for i=1:len
                if strcmp(harnessDataArrayfromtable(i).modelName,item)
                    % 删除这个配置项并重新保存
                    harnessDataArrayfromtable(i) = [];  % 删除该元素
                    save('harnessfromtable.mat', 'harnessDataArrayfromtable');  % 保存更新后的数据
                    break
                end
            end

            % 删除这个列表项中的item
            items = app.HarnessParamsListBox.Items;
            idx = find(strcmp(items, item));
            if ~isempty(idx)
                items(idx) = [];  % 从列表中删除该项
                app.HarnessParamsListBox.Items = items;  % 更新列表框
            end
        end

        % Menu selected function: DelCaseMenu
        function DelCaseMenuSelected(app, event)
            SelectedNode = app.CaseListTree.SelectedNodes;
            delete(SelectedNode)
        end

        % Callback function
        function TestManagerRunButtonPushed(app, event)
            modelName = app.CurrentModelEditField.Value;
            clear = app.ClearResultCheckBox.Value;
            run = app.RunAllHarnessCheckBox.Value;
            report = app.ExportReportCheckBox.Value;
            createTestManage(modelName, 'clear', clear, 'run', run, 'export', report)
        end

        % Callback function
        function RefreshButtonPushed(app, event)
            app.CurrentModelEditField.Value = bdroot;
        end

        % Callback function
        function findAlgebraicLoopsButtonPushed(app, event)
            result = findAlgebraicLoops(bdroot);
            if isempty(result)
                logStr = sprintf('No algebraic loops found in %s\n', bdroot);
            else
                logStr = sprintf('Algebraic loops found in %s\n', bdroot);
            end
            app.LogTextArea.Value = logStr;
        end

        % Callback function
        function findSlddCombineButtonPushed(app, event)
            subPath = "D:\Thermal_HY11\03_OutWorking\02_PCMU_Integration\Thermal_PCMU_23N7\SubModel";
            SLDD_NAME = 'PCMU_SLDD_All.xlsx';

            outPath = findSlddCombine(subPath, SLDD_NAME);

            logStr = sprintf("合并sldd 成功，其输出路径是%s\n",outPath);
            app.LogTextArea.Value = logStr;
        end

        % Callback function
        function delLinesAttrButtonPushed(app, event)
            attr = app.SigAttrDropDown.Value;
            FindAll = app.AffactAllevCheckBox.Value; 
            delLinesAttr(gcs,'attr', attr, 'FindAll',FindAll)
        end

        % Callback function
        function changeLinesPortAttrButtonPushed(app, event)
            scope = app.ScopeButtonGroup.SelectedObject.Text;
            if strcmp(scope,'gcb')
                scope = 'gcs';
            end
            valid_scopes = {'gcb', 'gcs', 'bdroot'};
            if ismember(scope, valid_scopes)
                path = feval(scope);  % 或者使用 path = eval(scope);
            else
                return
            end

            resoveValue = app.changeLinesPortResoveValueSwitch.Value;
            logValue = app.changeLinesPortLogValueSwitch.Value;
            testValue = app.changeLinesPortTestValueSwitch.Value;
            FindAll = app.ChangeLinePortAffactAllevCheckBox.Value;
            enableIn = app.EnableInCheckBox.Value;
            enableOut = app.EnableOutCheckBox.Value;

            resoveValue = strcmp(resoveValue,'On');
            logValue = strcmp(logValue,'On');
            testValue = strcmp(testValue,'On');

            changeLinesPortAttr(path, ...
                "resoveValue",resoveValue, ...
                "logValue",logValue, ...
                "testValue",testValue, ...
                "enableIn",enableIn, ...
                "enableOut",enableOut, ...
                "FindAll",FindAll)
        end

        % Callback function
        function CommonUsedModelsTextAreaValueChanged(app, event)
            value = app.CommonUsedModelsTextArea.Value;
            configSet('com_used_model',value);
        end

        % Callback function
        function SetDefaultTemplateButtonPushed(app, event)
            % select file through gui
            template_file = uigetfile('*.xlsx', 'Select Template File');
            if isequal(template_file, 0)
                % User cancelled the dialog box.
                return;
            end
            % 将app.DcmFilePath 的文本内容，设置成dcm_file
            app.TemplateFilePath.Value = template_file;

            configSet('template_file',template_file);
        end

        % Callback function
        function ConfigTabButtonDown(app, event)

            app.LogTextArea.Value = '';

            config = configGet();

            com_used_model = config.com_used_model;
            template_file = config.template_file;

            app.CommonUsedModelsTextArea.Value = com_used_model;
            app.TemplateFilePath.Value = template_file;
        end

        % Callback function
        function changeGotoSizeButtonPushed(app, event)
            font = app.FontSpinner.Value;
            scope = app.ScopeButtonGroup.SelectedObject.Text;

            valid_scopes = {'gcb', 'gcs', 'bdroot'};
            if ismember(scope, valid_scopes)
                path = feval(scope);  % 或者使用 path = eval(scope);
            else
                return
            end
            changeGotoSize(path,'font', font)

        end

        % Callback function
        function OpenFileToLoadButtonPushed(app, event)
            % select file through gui
            sldd_file = uigetfile('*.xlsx', 'Select Sldd File');
            if isequal(sldd_file, 0)
                % User cancelled the dialog box.
                return;
            end
            findSlddLoad(sldd_file)
        end

        % Callback function
        function CommonUsedContorlersTextAreaValueChanged(app, event)
            value = app.CommonUsedContorlersTextArea.Value;
            configSet('com_used_controllers',value);
        end

        % Callback function
        function MCUTypeDropDownValueChanged(app, event)
            value = app.MCUTypeDropDown.Value;
            configSet('active_controller',value);
        end

        % Callback function
        function findSlddLoadButtonPushed(app, event)
            submod = app.SlddDropDown.Value;
            active_controller = configGet('active_controller');
            findSlddLoad(submod,'mode',active_controller)
            findSlddLoad('TmSwArch','mode',active_controller)
        end

        % Callback function
        function changeModNameButtonPushed(app, event)
            scope = app.ScopeButtonGroup.SelectedObject.Text;
            if strcmp(scope,'gcb')
                scope = 'bdroot';
            end
            valid_scopes = {'gcb', 'gcs', 'bdroot'};
            if ismember(scope, valid_scopes)
                path = feval(scope);  % 或者使用 path = eval(scope);
            else
                return
            end

            UseLastName = app.UseLastNameCheckBox.Value;
            changeModName(path,'UseLastName',UseLastName)
        end

        % Callback function
        function SaveButtonPushed(app, event)

            configSet('GotoWid', app.GotoWidEditField.Value)
            configSet('GotoHeight', app.GotoHeightEditField.Value)
            configSet('GotoColor', app.GotoColorEditField.Value)
            configSet('FromColor', app.FromColorEditField.Value)
            
            configSet('PortWid', app.PortWidEditField.Value)
            configSet('PortHeight', app.PortHeightEditField.Value)
            configSet('InportColor', app.InportColorEditField.Value)
            configSet('OutportColor', app.OutportColorEditField.Value)

            configSet('com_used_model',app.CommonUsedModelsTextArea.Value);
            configSet('com_used_controllers',app.CommonUsedContorlersTextArea.Value);


        end

        % Callback function
        function ChangeGotoAttrButtonPushed(app, event)
            scope = app.ScopeButtonGroup.SelectedObject.Text;
            valid_scopes = {'gcb', 'gcs', 'bdroot'};
            if ismember(scope, valid_scopes)
                path = feval(scope);  % 或者使用 path = eval(scope);
            else
                return
            end
            config = configGet();
            GotoWid = config.GotoWid;
            GotoHeight = config.GotoHeight;
            GotoColor = config.GotoColor;
            FromColor = config.FromColor;

            changeGotoAttr(path, 'Type', 'goto', ...
                'wid', str2double(GotoWid), ...
                'height', str2double(GotoHeight), ...
                'BackgroundColor', GotoColor...
                )

            changeGotoAttr(path, 'Type', 'from', ...
                'wid', str2double(GotoWid), ...
                'height', str2double(GotoHeight), ...
                'BackgroundColor', FromColor...
                )
        end

        % Callback function
        function ChangePortAttrButtonPushed(app, event)
            scope = app.ScopeButtonGroup.SelectedObject.Text;
            valid_scopes = {'gcb', 'gcs', 'bdroot'};
            if ismember(scope, valid_scopes)
                path = feval(scope);  % 或者使用 path = eval(scope);
            else
                return
            end
            config = configGet();
            PortWid = config.PortWid;
            PortHeight = config.PortHeight;
            InportColor = config.InportColor;
            OutportColor = config.OutportColor;

            changePortAttr(path, 'Type', 'in', ...
                'wid', str2double(PortWid), ...
                'height', str2double(PortHeight), ...
                'BackgroundColor', InportColor...
                )

            changePortAttr(path, 'Type', 'out', ...
                'wid', str2double(PortWid), ...
                'height', str2double(PortHeight), ...
                'BackgroundColor', OutportColor...
                )
        end

        % Callback function
        function OpenModelButtonPushed(app, event)
            submod = app.SubModDropDown.Value;
            if strcmp(submod,'bdroot')
                warning('bdroot is not aviliable, you should assign a model name')
            end
            open_system(submod)
        end

        % Button pushed function: Export2ExcelButton
        function Export2Excel(app, event)
            filename = 'testdata.xlsx';
            harnessDataArrayfromtable_Loc =  load('harnessfromtable.mat');
            params_Loc = harnessDataArrayfromtable_Loc.harnessDataArrayfromtable;
            ModelNum=size(params_Loc,2);
            baseline=cell(1000,50);
            row=1;
            for i=1:ModelNum
                row=row+2;
                modelname_inf=params_Loc(i).modelName
                baseline{row,1}=modelname_inf;
                row=row+1;
                inName=params_Loc(i).inName{1};
                rstName=params_Loc(i).rstName{1};
                modelcase_title=[{'lev1','lev2','HoldTime'},inName,rstName];
                titlesize=size(modelcase_title,2);
                baseline(row,1:titlesize)=modelcase_title;
                row=row+1;
                lev1Num=size(params_Loc(i).lev1,2);
                for j=1:lev1Num
                    lev2Num=size(params_Loc(i).lev2{j},2);
                    for k=1:lev2Num
                        lev1_content=params_Loc(i).lev1{j};
                        lev2_content=params_Loc(i).lev2{j}{k};
                        HoldTime_content=params_Loc(i).HoldTime{j}{k};
                        inValue_content=params_Loc(i).inValue{j}{k};
                        rstValue_content=params_Loc(i).rstValue{j}{k};
                        modelcase_content=[lev1_content,lev2_content,HoldTime_content,inValue_content,rstValue_content];
                        baseline(row,1:titlesize)=modelcase_content;
                        row=row+1;
                    end
                end
            end
            xlswrite(filename,baseline);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.ButtonDownFcn = createCallbackFcn(app, @TabGroupButtonDown, true);
            app.TestTab = uitab(app.TabGroup);
            app.TestTab.Title = 'Test';
            app.TestTab.ButtonDownFcn = createCallbackFcn(app, @TestTabButtonDown, true);
            app.CaseTable = uitable(app.TestTab);
            app.CaseTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.CaseTable.RowName = {};
            app.CaseTable.ColumnEditable = true;
            app.CaseTreePanel = uipanel(app.TestTab);
            app.CaseTreePanel.Title = 'CaseTree';
            app.HarnessHistoryParamsPanel = uipanel(app.TestTab);
            app.HarnessHistoryParamsPanel.Title = 'Harness History Params';
            app.HarnessParamsListBox = uilistbox(app.HarnessHistoryParamsPanel);
            app.HarnessParamsListBox.Items = {};
            app.HarnessParamsListBox.ValueChangedFcn = createCallbackFcn(app, @HarnessParamsListBoxValueChanged, true);
            app.HarnessParamsListBox.ClickedFcn = createCallbackFcn(app, @HarnessParamsListBoxClicked, true);
            app.HarnessParamsListBox.Value = {};
            app.TestHarnessPanel = uipanel(app.TestTab);
            app.TestHarnessPanel.Title = 'TestHarness';
            app.AddCaseButton = uibutton(app.TestHarnessPanel, 'push');
            app.AddCaseButton.ButtonPushedFcn = createCallbackFcn(app, @AddCaseButtonPushed, true);
            app.AddCaseButton.Text = 'AddCase';
            app.GetPortsButton = uibutton(app.TestHarnessPanel, 'push');
            app.GetPortsButton.ButtonPushedFcn = createCallbackFcn(app, @GetPortsButtonPushed, true);
            app.GetPortsButton.Text = 'GetPorts';
            app.createHarnessButton = uibutton(app.TestHarnessPanel, 'push');
            app.createHarnessButton.ButtonPushedFcn = createCallbackFcn(app, @createHarnessButtonPushed, true);
            app.createHarnessButton.Text = 'createHarness';
            app.TestCaseTextAreaLabel = uilabel(app.TestHarnessPanel);
            app.TestCaseTextAreaLabel.Text = 'TestCase';
            app.TestCaseTextArea = uitextarea(app.TestHarnessPanel);
            app.TestCaseTextArea.Placeholder = 'test case that need to add to the tree';
            app.TestCaseTextArea.Value = {'LEV1'; 'LEV2'};
            app.LastStepDropDownLabel = uilabel(app.TestHarnessPanel);
            app.LastStepDropDownLabel.Text = 'LastStep';
            app.LastStepDropDown = uidropdown(app.TestHarnessPanel);
            app.LastStepDropDown.Items = {'Initialize'};
            app.LastStepDropDown.Value = 'Initialize';
            app.NextStepDropDownLabel = uilabel(app.TestHarnessPanel);
            app.NextStepDropDownLabel.Text = 'NextStep';
            app.NextStepDropDown = uidropdown(app.TestHarnessPanel);
            app.NextStepDropDown.Items = {'None'};
            app.NextStepDropDown.Value = 'None';
            app.Export2ExcelButton = uibutton(app.TestHarnessPanel, 'push');
            app.Export2ExcelButton.ButtonPushedFcn = createCallbackFcn(app, @Export2Excel, true);
            app.Export2ExcelButton.Text = 'Export2Excel';
            app.CaseListTree = uitree(app.TestTab);
            app.CaseListTree.SelectionChangedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.LogTextArea = uitextarea(app.UIFigure);
            app.LogTextArea.Value = {'Welcom ! '; 'Welcom ! !'; 'Welcom ! ! !'};
            app.ContextMenuCaseTab = uicontextmenu(app.UIFigure);
            app.DelSelectMenu = uimenu(app.ContextMenuCaseTab);
            app.DelSelectMenu.MenuSelectedFcn = createCallbackFcn(app, @DelSelectMenuSelected, true);
            app.DelSelectMenu.Text = 'DelSelect';
            app.DelAllMenu = uimenu(app.ContextMenuCaseTab);
            app.DelAllMenu.MenuSelectedFcn = createCallbackFcn(app, @DelAllMenuSelected, true);
            app.DelAllMenu.Text = 'DelAll';
            % Assign app.ContextMenuCaseTab
            app.CaseTable.ContextMenu = app.ContextMenuCaseTab;
            app.ContextMenuHarnessParams = uicontextmenu(app.UIFigure);
            app.DelParamMenu = uimenu(app.ContextMenuHarnessParams);
            app.DelParamMenu.MenuSelectedFcn = createCallbackFcn(app, @DelParamMenuSelected, true);
            app.DelParamMenu.Tooltip = {'删除参数'};
            app.DelParamMenu.Text = 'DelParam';
            % Assign app.ContextMenuHarnessParams
            app.HarnessParamsListBox.ContextMenu = app.ContextMenuHarnessParams;
            app.ContextMenuCaseTree = uicontextmenu(app.UIFigure);
            app.DelCaseMenu = uimenu(app.ContextMenuCaseTree);
            app.DelCaseMenu.MenuSelectedFcn = createCallbackFcn(app, @DelCaseMenuSelected, true);
            app.DelCaseMenu.Text = 'DelCase';
            % Assign app.ContextMenuCaseTree
            app.CaseTreePanel.ContextMenu = app.ContextMenuCaseTree;
            app.CaseListTree.ContextMenu = app.ContextMenuCaseTree;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = draft_smart_thermal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end