classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Menu_3                    matlab.ui.container.Menu
        Menu_4                    matlab.ui.container.Menu
        Menu_5                    matlab.ui.container.Menu
        update_slddButton         matlab.ui.control.Button
        exportparameterButton     matlab.ui.control.Button
        read_davinci_array        matlab.ui.control.Button
        AutoAdjustPosButton       matlab.ui.control.Button
        Label_4                   matlab.ui.control.Label
        A2LSwitch                 matlab.ui.control.Switch
        Label_3                   matlab.ui.control.Label
        current_filename          matlab.ui.control.EditField
        Label_2                   matlab.ui.control.Label
        ListBox_2                 matlab.ui.control.ListBox
        ListBox                   matlab.ui.control.ListBox
        CompareDCMButton          matlab.ui.control.Button
        EditField_2               matlab.ui.control.EditField
        Label                     matlab.ui.control.Label
        DCMButton                 matlab.ui.control.Button
        Image2                    matlab.ui.control.Image
        Image                     matlab.ui.control.Image
        getportinformationButton  matlab.ui.control.Button
        generalinputoutputportexcelButton  matlab.ui.control.Button
        renameportButton          matlab.ui.control.Button
        ContextMenu               matlab.ui.container.ContextMenu
        Menu                      matlab.ui.container.Menu
        Menu_2                    matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: renameportButton
        function renameportButtonPushed(app, event)
      %
%BlockPaths_input=find_system(bdroot,'SearchDepth',1,'BlockType','Inport');
%BlockPaths_output=find_system(bdroot,'SearchDepth',1,'BlockType','Outport');
Block_name=get(gcbh,'Name');
% h1=get_param(gcb,'LineHandles');
% h2=get_param(gcb,'PortConnectivity');

TopBlock_name=Block_name(numel(Block_name)-2:numel(Block_name));%后三个字符如果时sys结尾证明为顶层模块
if TopBlock_name == 'sys'
    BlockPaths_input=find_system(bdroot,'SearchDepth',1,'BlockType','Inport');
    BlockPaths_output=find_system(bdroot,'SearchDepth',1,'BlockType','Outport');
else   
    BlockPaths_input=find_system(gcb,'SearchDepth',1,'BlockType','Inport');
    BlockPaths_output=find_system(gcb,'SearchDepth',1,'BlockType','Outport');
   Block_name=get(gcbh,'Name');
end
L_input=length(BlockPaths_input);
L_output=length(BlockPaths_output);
c=cell(80);
c(:)=cellstr(" ");
%Data(:)=[];
Data={};
Value_input={};
Value_inputType={};%清空以前数据防止存储上一个子模型的数据
Value_output={};
Value_outputType={};
for i=1:L_input %读取input cell 数据
    Value_input(i)=get_param(BlockPaths_input(i),'Name');
    Value_inputType(i)=get_param(BlockPaths_input(i),'OutDataTypeStr');
     strlist1=split( Value_input(i),'_');
     shuzugeshu1=numel(strlist1);%求数组个数
      if shuzugeshu1==2
             if(i~=1)
               Value_input(i) = strlist1(shuzugeshu1,1); %带一个下划线直接用后面的cell即可,将 runnable 考虑在内，runnable不需要切割
             end         
        elseif shuzugeshu1==3
            strlist3 = append(strlist1{2,1},"_",strlist1{3,1});%带两个下划线要连接字符串
            %strlist3 =strlist1{2,1}
            Value_input(i) =cellstr(strlist3);  %带两个下划线要拼接一下
      end
     set_param(char(BlockPaths_input(i)),'name',char(Value_input(i)));%不要羡慕哥，哥只是个传说 
end

Value_input=Value_input'; %行转列
Value_inputType=Value_inputType';

for i=1:L_output %读取output cell 数据
    Value_output(i)=get_param(BlockPaths_output(i),'Name');
    Value_outputType(i)=get_param(BlockPaths_output(i),'OutDataTypeStr');
    strlist2=split( Value_output(i),'_');
    shuzugeshu2=numel(strlist2);
        if shuzugeshu2==2
                Value_output(i) = strlist2(shuzugeshu2,1);        
        elseif shuzugeshu2==3
        Value_output(i) =cellstr(append(strlist2{2,1},"_",strlist2{3,1}));
       % Value_output(i) =cellstr(strlist2{2,1})
        end
      set_param(char(BlockPaths_output(i)),'name',char(Value_output(i))); %不要羡慕哥，哥只是个传说 
end

hangshu=2;
Value_output=Value_output'; %行转列
Value_outputType=Value_outputType';
Data(1,1)=cellstr("序号");
Data(1,2)=cellstr("inputType");
Data(1,3)=cellstr("input");
Data(7,8)=cellstr("Block_name：");
Data(8,8)=cellstr(Block_name);

Data(1+L_input+1+hangshu,1)=cellstr("序号");
Data(1+L_input+1+hangshu,2)=cellstr("outputType");
Data(1+L_input+1+hangshu,3)=cellstr("output");

for i=1:L_input
   Data(i+1,1)=num2cell(i); 
   Data(i+1,2)=Value_inputType(i,1);
   Data(i+1,3)=Value_input(i,1);
end
for i=1:L_output
   Data(i+1+L_input+1+hangshu,1)=num2cell(i); 
   Data(i+1+L_input+1+hangshu,2)=Value_outputType(i,1); 
   Data(i+1+L_input+1+hangshu,3)=Value_output(i,1);%修改把output和input修改为1列
  

end
Value_input=Value_input'; %行转列
Value_output=Value_output'; %转回来，不然老出错
Value_inputType=Value_inputType';
Value_outputType=Value_outputType';

fullpath = mfilename('fullpath');
[path,name]=fileparts(fullpath);
filename = [path,'\','接口','.xlsx'];
xlRange = 'A1';
%sheet = 1;
%xlswrite(filename,c,sheet,'A1:G200');%clc old excel data
%xlswrite(filename,Data,sheet,xlRange);
sheet = Block_name;
 if(strlength(sheet)>=31)
     sheet=sheet(1:30);
 end
 fprintf('%s\n'," change successful!");
%xlswrite(filename,Data,sheet,xlRange);
        end

        % Button pushed function: generalinputoutputportexcelButton
        function generalinputoutputportexcelButtonPushed(app, event)
                  
Block_name=get(gcbh,'Name');
TopBlock_name=Block_name(numel(Block_name)-2:numel(Block_name));%后三个字符如果时sys结尾证明为顶层模块
if TopBlock_name == 'sys'
    BlockPaths_input=find_system(bdroot,'SearchDepth',1,'BlockType','Inport');
    BlockPaths_output=find_system(bdroot,'SearchDepth',1,'BlockType','Outport');
else   
    BlockPaths_input=find_system(gcb,'SearchDepth',1,'BlockType','Inport');
    BlockPaths_output=find_system(gcb,'SearchDepth',1,'BlockType','Outport');
   Block_name=get(gcbh,'Name');
end
L_input=length(BlockPaths_input);
L_output=length(BlockPaths_output);
c=cell(80);
c(:)=cellstr(" ");
%Data(:)=[];
Data={};
Dataclc={};


Value_input={};
Value_inputType={};%清空以前数据防止存储上一个子模型的数据
Value_output={};
Value_outputType={};
for i=1:L_input %读取input cell 数据
    Value_input(i)=get_param(BlockPaths_input(i),'Name');
    Value_inputType(i)=get_param(BlockPaths_input(i),'OutDataTypeStr');
     strlist1=split( Value_input(i),'_');
    %fprintf('%d',i)
     shuzugeshu1=numel(strlist1);%求数组个数
      if shuzugeshu1==2
           Value_input(i) = strlist1(shuzugeshu1,1); %带一个下划线直接用后面的cell即可
        elseif shuzugeshu1==3
            strlist3 = append(strlist1{2,1},"_",strlist1{3,1});%带两个下划线要连接字符串
            Value_input(i) =cellstr(strlist3);  %带两个下划线要拼接一下
       end
end
Value_input=Value_input'; %行转列
Value_inputType=Value_inputType';

for i=1:L_output %读取output cell 数据
    Value_output(i)=get_param(BlockPaths_output(i),'Name');
    Value_outputType(i)=get_param(BlockPaths_output(i),'OutDataTypeStr');
    strlist2=split( Value_output(i),'_');
    shuzugeshu2=numel(strlist2);
%Value_output(i)
        if shuzugeshu2==2
           Value_output(i) = strlist2(shuzugeshu2,1);
        elseif shuzugeshu2==3
         Value_output(i) =cellstr(append(strlist2{2,1},"_",strlist2{3,1}));
       end
         
end
hangshu=2;
Value_output=Value_output'; %行转列
Value_outputType=Value_outputType';
Data(1,1)=cellstr("序号");
Data(1,2)=cellstr("inputType");
Data(1,3)=cellstr("input");
Data(7,8)=cellstr("Block_name：");
Data(8,8)=cellstr(Block_name);

Data(1+L_input+1+hangshu,1)=cellstr("序号");
Data(1+L_input+1+hangshu,2)=cellstr("outputType");
Data(1+L_input+1+hangshu,3)=cellstr("output");

for i=1:L_input
   Data(i+1,1)=num2cell(i); 
   Data(i+1,2)=Value_inputType(i,1);
   Data(i+1,3)=Value_input(i,1);
end
for i=1:L_output
   Data(i+1+L_input+1+hangshu,1)=num2cell(i); 
   Data(i+1+L_input+1+hangshu,2)=Value_outputType(i,1); 
   Data(i+1+L_input+1+hangshu,3)=Value_output(i,1);%修改把output和input修改为1列
  

end
Value_input=Value_input'; %行转列
Value_output=Value_output'; %转回来，不然老出错
Value_inputType=Value_inputType';
Value_outputType=Value_outputType';

fullpath =pwd;%mfilename('fullpath');将软件运行目录改为matlab当前工作目录

%[path,name]=fileparts(fullpath);
%filename = [path,'\','接口','.xlsx'];
filenametest=split(gcs,'/');
 if numel(split(gcs,'/'))~=1
     filenametest=filenametest(1,1);
 end
filenametest=char(filenametest);

filename = [fullpath,'\',filenametest,'_接口','.xlsx'];%使用模型顶层名字作为输出文件名
xlRange = 'A1';
%sheet = 1;
%xlswrite(filename,c,sheet,'A1:G200');%clc old excel data
%xlswrite(filename,Data,sheet,xlRange);
sheet = Block_name;
 if(strlength(sheet)>=31)
     sheet=sheet(1:30);
 end
fprintf('%s\n',filename);
Dataclc=Data;
Dataclc(:,1:6)=[];

xlswrite(filename,Dataclc,sheet,xlRange);%clc数据
xlswrite(filename,Data,sheet,xlRange);
msgbox('excel生成成功');

        end

        % Button pushed function: getportinformationButton
        function getportinformationButtonPushed(app, event)
            get(gcbh) 
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
           persistent  choose;
            pathToMLAPP = fileparts(mfilename('fullpath'));
            pathToMLAPP=strcat(pathToMLAPP,"\picture");
          if isempty(choose)        
              app.Image2.ImageSource = fullfile(pathToMLAPP, 'playball.gif');
              disp(pathToMLAPP);
              choose=0;
          elseif(choose==0)         
           app.Image2.ImageSource = fullfile(pathToMLAPP, 'sit_here.gif'); 
           choose=choose+1;       
          elseif(choose==1)
          app.Image2.ImageSource = fullfile(pathToMLAPP, 'shake_head.gif'); 
           choose=choose+1;     
          elseif(choose==2)
          app.Image2.ImageSource = fullfile(pathToMLAPP, 'jump.gif'); 
           choose=choose+1;    
          elseif(choose==3)
          app.Image2.ImageSource = fullfile(pathToMLAPP, 'dancing.gif'); 
           choose=choose+1;   
          else
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'playball.gif'); 
             choose=0;
          end
          
        end

        % Menu selected function: Menu_4
        function Menu_4Selected(app, event)
                %app.Image.Visible = 'on';
                %app.Image2.Visible = 'on';
        end

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
                %app.Image.Visible = 'off';
                %app.Image2.Visible = 'off';
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
              app.Image2.Visible = 'on';
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
             app.Image2.Visible = 'off';
        end

        % Button pushed function: DCMButton
        function DCMButtonPushed(app, event)

[file,path] = uigetfile('*.DCM');
if isequal(file,0)
   disp('User selected Cancel');
    return
else
   disp(['User selected ', fullfile(path,file)]);
end
            
            
 %           fullpath_dcm= mfilename('fullpath');
%[path,name]=fileparts(fullpath_dcm);
%filename = [path,'\','111','.DCM'];
filename=[path,file]; 
filetext =fileread(filename);
% expr = ['(?<=(FESTWERT|GRUPPENKENNFELD|GRUPPENKENNLINIE...' ...
%     '|FESTWERTEBLOCK)\s)[a-zA-Z0-9\.]*'];%匹配表名
% expr2='(?<=(FESTWERT|GRUPPENKENNFELD|GRUPPENKENNLINIE|FESTWERTEBLOCK)\s)[\s\S]*?(?=END)';
expr = '(?<=(FESTWERT|GRUPPENKENNFELD|GRUPPENKENNLINIE|FESTWERTEBLOCK|STUETZSTELLENVERTEILUNG)\s)[a-zA-Z0-9\.]*';%匹配表名
expr2= '(?=(FESTWERT|GRUPPENKENNFELD|GRUPPENKENNLINIE|FESTWERTEBLOCK|STUETZSTELLENVERTEILUNG)\s)[\s\S]*?(?=END)';%匹配每个表里面的内容
expr_withnoA2l='(?<=(FESTWERT|GRUPPENKENNFELD|GRUPPENKENNLINIE)\s)[a-zA-Z0-9\.]*';%匹配表名
expr2_withnoA2l='(?=(FESTWERT|GRUPPENKENNFELD|GRUPPENKENNLINIE)\s)[\s\S]*?(?=END)';%匹配每个表里面的内容
          
%STUETZSTELLENVERTEILUNG轴名

global tableNameAll ;
global slectnumber;
global slecttablename;
global tableContextAll ;
global tableNameAll_withA2l ;
global tableContextAll_withA2l ;
global tableNameAll_withnoA2l ;
global tableContextAll_withnoA2l ;
global tableNameAll_export ;
global tableContextAll_export ;

tableNameAll_export=[];
tableContextAll_export=[];
slectnumber=[];
slecttablename=[];
filetextupdate=filetext;
tableNameAll_withA2l=regexp(filetext,expr,'match');%匹配表名
tableContextAll_withA2l=regexp(filetext,expr2,'match');%匹配每个表里面的内容
tableNameAll_withnoA2l=regexp(filetext,expr_withnoA2l,'match');%匹配表名
tableContextAll_withnoA2l=regexp(filetext,expr2_withnoA2l,'match');%匹配每个表里面的内容
tableNameAll_export=tableNameAll;
tableContextAll_export=tableContextAll;
if(strcmp(app.A2LSwitch.Value,'Off'))
    tableNameAll=tableNameAll_withA2l';
    tableContextAll=tableContextAll_withA2l;
    app.current_filename.Value=num2str(length(tableNameAll));
     app.ListBox.Items=tableNameAll;
% for i=1:length(matches)
%     %aa=sprintf('%s\r\n',matches{i});
%     aa=matches{i};
%     app.TextArea.Value =[app.TextArea.Value;aa];
%     %app.TextArea.Value=sprintf('%s%s',app.TextArea.Value,matches{i});
%      %app.TextArea.Value =append(app.TextArea.Value,aa);
%      
% end
   
elseif(strcmp(app.A2LSwitch.Value,'On'))
    tableNameAll=tableNameAll_withnoA2l';
    tableContextAll=tableContextAll_withnoA2l;
    app.current_filename.Value=num2str(length(tableNameAll));
    app.ListBox.Items=tableNameAll;

end  
app.Label_4.Visible="on";
app.Label_4.Text=['当前文件：',file];


        end

        % Value changing function: EditField_2
        function EditField_2ValueChanging(app, event)
            changingValue = event.Value;
            global tableNameAll ;
            global slectnumber;
            global slecttablename;
            TF = contains(tableNameAll,changingValue,'IgnoreCase',true);
            slectnumber=find(TF);
            
            slecttablename=tableNameAll(TF);
            app.ListBox.Items=slecttablename;
            app.current_filename.Value=num2str(length(slecttablename));
        end

        % Double-clicked callback: ListBox
        function ListBoxDoubleClicked(app, event)
            global tableContextAll ;
            global slectnumber;
            global tableNameAll;
            if (isempty(slectnumber))
                item_number =event.InteractionInformation.Item;%直接读取到值到列表，不需要筛选
            else
                item_number = slectnumber(event.InteractionInformation.Item);%直接读取到值到列表，筛选出来两个，选一个
               % slectnumber=[];%不同dcm文件导入的时候，如果不清slectnumber，会报索引错误，清空已经放到读取dcm那个按钮处理了。
            end
            select_item=char(tableContextAll(item_number));
            slecttablename_str=char(tableNameAll(item_number));
            table_type=split(select_item," ");
            table_type=table_type{1,1};
            table_name=app.ListBox.Items(event.InteractionInformation.Item);
            
            if (table_type=="FESTWERT")
                    expr_value='(?<=\sWERT|TEXT\s).*';
                    table_value=regexp(select_item,expr_value,'match');%匹配值
                    table_value_s=char(table_value);
                    fig = uifigure;
                    myData = {table_value_s};
                    uit = uitable(fig,'ColumnWidth','fit',"Data",myData);
                    uit.Position = [20 20 400 278];
                    uit.ColumnName=table_name;
                    uit.RowName={'value'};

                                    
                    ef = uieditfield(fig,"text");  
                    ef.Position = [60 300 280 20];               
                   ef.Value=  ['表名：',char(slecttablename_str)];  
            elseif(table_type=="GRUPPENKENNFELD")
                    %expr_Axisvalue='(?<=ST/X\s{3}).*?(?=\n)';
                    expr_Axisvalue='(?<=ST/X\s{3}).*?(?=\s*\n)';
                    expr_Ayisvalue='(?<=ST/Y\s{3}).*?(?=\s*\n)';
                    expr_Wertvalue='(?<=WERT\s{3}).*?(?=\s*\n)';
                    expr_Axisname='(?<=SSTX\s)\w*';
                    expr_Ayisname='(?<=SSTY\s)\w*';
                    table_Axisxvalue=regexp(select_item,expr_Axisvalue,'match');
                    table_Axisyvalue=regexp(select_item,expr_Ayisvalue,'match');
                    table_Wertvalue=regexp(select_item,expr_Wertvalue,'match');
                    
                    table_Axisyvalue_num=size(table_Axisyvalue);
                    %x轴坐标
                    table_Axisxvalue_split=join(table_Axisxvalue);
                   table_Axisxvalue_split=split(table_Axisxvalue_split," ");
                    table_Axisxvalue_split=table_Axisxvalue_split';
                    table_Axisxvalue_split(cellfun(@isempty,table_Axisxvalue_split))=[];
                    %y轴坐标  
                    table_Axisyvalue_split=join(table_Axisyvalue);
                   table_Axisyvalue_split=split(join(table_Axisyvalue)," ");
                    table_Axisyvalue_split(cellfun(@isempty,table_Axisyvalue_split))=[];
                    %实际值
                    table_Wertvalue_split=join(table_Wertvalue);
                    table_Wertvalue_split=split(table_Wertvalue_split," ");
                   
                    table_Wertvalue_split(cellfun(@isempty,table_Wertvalue_split))=[];
                    
                    table_Wertvalue_splitstr= reshape(table_Wertvalue_split, [], table_Axisyvalue_num(1,2));
                    table_Wertvalue_splitstr=table_Wertvalue_splitstr' ;
                    table_xvaluename=regexp(select_item,expr_Axisname,'match');
                    table_xvaluename_str=char(table_xvaluename);
                    table_yvaluename=regexp(select_item,expr_Ayisname,'match');
                    table_yvaluename_str=char(table_yvaluename);
                    
                
                    %myData ={'aa' 'cc';'ab' 'cb';'ab' 'cb';} %table_Axisxvalue_str;
                    fig = uifigure;
                    ef = uieditfield(fig,"text");  
                    ef.Position = [60 300 210 20];               
                    ef.Value=  ['x轴：',table_xvaluename_str];
                   
                   
                    ef1 = uieditfield(fig,"text");  
                    ef1.Position = [60 330 210 20];               
                    ef1.Value=  ['y轴：',table_yvaluename_str];


                    ef2 = uieditfield(fig,"text");  
                    ef2.Position = [60 360 250 20];               
                    ef2.Value=  ['表名：',char(slecttablename_str)];   


                    myData =table_Wertvalue_splitstr;
                    uit = uitable(fig,'ColumnWidth','fit',"Data",myData);
                    uit.Position = [20 20 400 278];
                    %uit.ColumnName={table_xvaluename_str;table_yvaluename_str};
                    uit.ColumnName=table_Axisxvalue_split;
                    uit.RowName=table_Axisyvalue_split;
            elseif(table_type=="GRUPPENKENNLINIE"||table_type=="FESTWERTEBLOCK")
                    expr_Axisvalue='(?<=ST/X\s{3}).*?(?=\s*\n)';
                    
                    expr_Wertvalue='(?<=WERT\s{3}).*?(?=\s*\n)';
                    expr_Axisname='(?<=SSTX\s)\w*';
                    
                    table_Axisxvalue=regexp(select_item,expr_Axisvalue,'match'); 
                    table_Wertvalue=regexp(select_item,expr_Wertvalue,'match');
                    
                    table_Axisxvalue_num=size(table_Axisxvalue);
                    %x轴坐标
                    table_Axisxvalue_split=join(table_Axisxvalue);
                    table_Axisxvalue_split=split(table_Axisxvalue_split," ");
                    table_Axisxvalue_split=table_Axisxvalue_split';
                    table_Axisxvalue_split(cellfun(@isempty,table_Axisxvalue_split))=[];
                    
                    %实际值
                    table_Wertvalue_split=join(table_Wertvalue);
                    table_Wertvalue_split=split(table_Wertvalue_split," ");
                   
                    table_Wertvalue_split(cellfun(@isempty,table_Wertvalue_split))=[];
                    table_Wertvalue_splitstr=table_Wertvalue_split' ;
                    table_xvaluename=regexp(select_item,expr_Axisname,'match');
                    table_xvaluename_str=char(table_xvaluename);
                    
                    fig = uifigure;
                    ef = uieditfield(fig,"text");  
                    ef.Position = [60 300 210 20];               
                    ef.Value=  ['x轴：',table_xvaluename_str];
                   
                    ef2 = uieditfield(fig,"text");  
                    ef2.Position = [60 330 250 20];               
                    ef2.Value=  ['表名：',char(slecttablename_str)];     

                    myData =table_Wertvalue_splitstr;
                    uit = uitable(fig,'ColumnWidth','fit',"Data",myData);
                    uit.Position = [20 20 400 278];
                    uit.RowName={'z'};
                    uit.ColumnName=table_Axisxvalue_split;     

                ;
            
            end       
          
        end

        % Value changed function: A2LSwitch
        function A2LSwitchValueChanged(app, event)
        global tableNameAll ;
        global tableContextAll ;
        global tableNameAll_withA2l ;
        global tableContextAll_withA2l ;
        global tableNameAll_withnoA2l ;
        global tableContextAll_withnoA2l ;       
        global slecttablename;    
            value = app.A2LSwitch.Value;
    if(isempty(app.EditField_2.Value))
                   if(strcmp(value,'On'))
            tableNameAll=tableNameAll_withnoA2l';
            tableContextAll=tableContextAll_withnoA2l;
            app.current_filename.Value=num2str(length(tableNameAll));
            app.ListBox.Items=tableNameAll;
                   elseif(strcmp(value,'Off'))
            tableNameAll=tableNameAll_withA2l';
            tableContextAll=tableContextAll_withA2l;
            app.current_filename.Value=num2str(length(tableNameAll));
             app.ListBox.Items=tableNameAll;
                   end  
    else
                   if(strcmp(value,'On'))
            tableNameAll=tableNameAll_withnoA2l';
            tableContextAll=tableContextAll_withnoA2l; 
                   elseif(strcmp(value,'Off'))
            tableNameAll=tableNameAll_withA2l';
            tableContextAll=tableContextAll_withA2l;             
                   end  
             TF = contains(tableNameAll,app.EditField_2.Value,'IgnoreCase',true);
            slectnumber=find(TF);
            slecttablename=tableNameAll(TF);
            app.ListBox.Items=slecttablename;
            app.current_filename.Value=num2str(length(slecttablename));
    end     
        end

        % Button pushed function: AutoAdjustPosButton
        function AutoAdjustPosButtonPushed(app, event)
            port_handles = get_param(gcbh,'PortHandles');
inport_handles = port_handles.Inport;
try
        for i = 1:length(inport_handles)
        
                line_handles = get_param(inport_handles(i),'Line');
                src_handles = get_param(line_handles,'SrcBlockHandle');
                
                port_pos = get_param(inport_handles(i),'Position');
                
                src_pos = get_param(src_handles,'Position');
                src_len = src_pos(3) - src_pos(1);
                src_width = src_pos(4) - src_pos(2);
                
                new_pos(1) = port_pos(1) - 100;
                new_pos(3) = new_pos(1) + src_len;
                new_pos(2) = port_pos(2) - fix(src_width/2);
                new_pos(4) = port_pos(2) + fix(src_width/2);        
                set_param(src_handles,'Position',new_pos);
        end
catch 
            fig = uifigure;
            uialert(fig,sprintf('%d inport is wrong',i),'Port 错误');

end


outport_handles = port_handles.Outport;
try
        for i = 1:length(outport_handles)
        
        line_handles = get_param(outport_handles(i),'Line');
        src_handles = get_param(line_handles,'DstBlockHandle');
        
        port_pos = get_param(outport_handles(i),'Position');
        
        src_pos = get_param(src_handles,'Position');
        src_len = src_pos(3) - src_pos(1);
        src_width = src_pos(4) - src_pos(2);
        
        new_pos(1) = port_pos(1) + 100;
        new_pos(3) = new_pos(1) + src_len;
        new_pos(2) = port_pos(2) - fix(src_width/2);
        new_pos(4) = port_pos(2) + fix(src_width/2);
        
        set_param(src_handles,'Position',new_pos);
        end
catch 
            fig = uifigure;
            uialert(fig,sprintf('%d outport is wrong',i),'Port 错误');

end
        end

        % Button pushed function: read_davinci_array
        function read_davinci_arrayButtonPushed(app, event)
   global  Davinci_array;
   global  Davinci_arraylengeth;
   Davinci_array=[];
   Davinci_arraylengeth=[];
   data={};
            [file,path] = uigetfile('*.c');
if isequal(file,0)
   disp('User selected Cancel');
    return
else
   disp(['User selected ', fullfile(path,file)]);
end
filename=[path,file]; 
filetext =fileread(filename);
expr = '([0-9a-zA-Z]+\,\r)|(\{[^\{\}]*\})|(\s+,)';%匹配代码数据
Davinci_array=regexp(filetext,expr,'match');%匹配表数据
Davinci_arraylengeth=length(Davinci_array);
 for i=1:Davinci_arraylengeth  %输出output cell 数据 
     data(2,i)=Davinci_array(1,i);
 end
data=data';
arrayfullpath =pwd;%mfilename('fullpath');将软件运行目录改为matlab当前工作目录
filename = [arrayfullpath,'\','davinci_array','.xlsx'];
xlRange = 'A1';
sheet ='array';
xlswrite(filename,data,sheet,xlRange);
msgbox('davinci_array   生成成功');

        end

        % Button pushed function: exportparameterButton
        function exportparameterButtonPushed(app, event)
           global tableNameAll_export;
           global tableContextAll_export;
           global table_updatevaluearray;
    data_parameter={};
    table_Wertvalue_splitstr_cell={};
    data_parameter(1,1)=cellstr('类别');
    data_parameter(2,1)=cellstr('Name');
    data_parameter(3,1)=cellstr('Value');
    data_parameter(4,1)=cellstr('X轴维度');
    data_parameter(5,1)=cellstr('Y轴维度');
tableNameAll_arraylengeth=length(tableNameAll_export);
 for i=1:tableNameAll_arraylengeth  %输出output cell 数据 
     data_parameter(2,i+1)=tableNameAll_export(i);
    
            select_item=char(tableContextAll_export(i));
            table_type=split(select_item," ");
            table_type=table_type{1,1};
     if (table_type=="FESTWERT")
         data_parameter(1,i+1)=cellstr('单独值类型');
                    expr_value='(?<=\sWERT|TEXT\s).*';
                    table_value=regexp(select_item,expr_value,'match');%匹配值
                    table_Wertvalue_splitstr=char(table_value);
                    table_Wertvalue_splitstr_cell=cellstr(table_Wertvalue_splitstr);
                   
                    table_Axisxvalue_num=num2cell(1);
                    table_Axisyvalue_num=num2cell(1);
     elseif(table_type=="GRUPPENKENNFELD")           %2维表      
                    data_parameter(1,i+1)=cellstr('2维表类型');
                    expr_Wertvalue='(?<=WERT\s{3}).*?(?=\s*\n)';
                    expr_Ayisvalue='(?<=ST/Y\s{3}).*?(?=\s*\n)';
                    expr_Axisvalue='(?<=ST/X\s{3}).*?(?=\s*\n)';
                    table_Wertvalue=regexp(select_item,expr_Wertvalue,'match');
                    table_Axisyvalue=regexp(select_item,expr_Ayisvalue,'match');     
                    table_Axisxvalue=regexp(select_item,expr_Axisvalue,'match');
                    table_Axisxvalue_split=join(table_Axisxvalue);
                    table_Axisxvalue_split=split(table_Axisxvalue_split," ");
                    table_Axisyvalue_split=join(table_Axisyvalue);             
                    table_Axisyvalue_split=split(table_Axisyvalue_split," ");
                    table_Axisxvalue_split(cellfun(@isempty,table_Axisxvalue_split))=[];%将分隔符分出的空cell 数组删除
                    table_Axisxvalue_split=str2double(table_Axisxvalue_split);
                    table_Axisyvalue_split(cellfun(@isempty,table_Axisyvalue_split))=[];
                    table_Axisxvalue_num=num2cell(length(table_Axisxvalue_split));
                    table_Axisyvalue_num=num2cell(length(table_Axisyvalue_split));

                    %实际值

                    table_Wertvalue=join(table_Wertvalue);
                    table_Wertvalue=split(table_Wertvalue," ");
                     table_Wertvalue(cellfun(@isempty,table_Wertvalue))=[];%将分隔符分出的空cell 数组删除
                     table_Wertvalue_splitstr= reshape(table_Wertvalue, [], length(table_Axisyvalue_split));
                    table_Wertvalue_splitstr=table_Wertvalue_splitstr' ;
                   % table_Wertvalue_splitstr=cell2mat(table_Wertvalue_splitstr);
                    table_Wertvalue_splitstr_cell=strjoin(table_Wertvalue_splitstr);
                    table_Wertvalue_splitstr_cell=cellstr(table_Wertvalue_splitstr_cell);
                   % table_Wertvalue_splitstr= mat2cell(table_Wertvalue_splitstr,[length(table_Axisyvalue_split)],[length(table_Axisxvalue_split)]);%转为单一原包数组
                   % table_Wertvalue_splitstr_cell=cellstr(table_Wertvalue_splitstr);
%table_Wertvalue_splitstr_cell=sprintf('%s',table_Wertvalue_splitstr);
                   % table_Wertvalue=str2double(table_Wertvalue);

                     %table_Wertvalue=sprintf('%.2f', table_Wertvalue);这个函数不好 
                 %   table_Wertvalue_splitstr=round(table_Wertvalue,2);%join(num2cell(table_Wertvalue));  
                 %   table_Wertvalue_splitstr=sprintf('%s', table_Wertvalue_splitstr);
                   % table_Wertvalue_splitstr=join(table_Wertvalue_splitstr);
      elseif(table_type=="GRUPPENKENNLINIE")%1维表
                    data_parameter(1,i+1)=cellstr('1维表类型');
                    expr_Axisvalue='(?<=ST/X\s{3}).*?(?=\s*\n)';          
                    expr_Wertvalue='(?<=WERT\s{3}).*?(?=\s*\n)';
                    table_Wertvalue=regexp(select_item,expr_Wertvalue,'match'); 
                    table_Wertvalue_splitstr=join(table_Wertvalue); 
                    table_Wertvalue_splitstr_cell=cellstr(table_Wertvalue_splitstr);
                    table_Axisxvalue=regexp(select_item,expr_Axisvalue,'match');
                    table_Axisxvalue_split=join(table_Axisxvalue);
                    table_Axisxvalue_split=split(table_Axisxvalue_split," ");
                    table_Axisxvalue_split(cellfun(@isempty,table_Axisxvalue_split))=[];
                    table_Axisxvalue_num=num2cell(1);
                    table_Axisyvalue_num=num2cell(length(table_Axisxvalue_split));
                   
      elseif(table_type=="STUETZSTELLENVERTEILUNG")%轴
                    data_parameter(1,i+1)=cellstr('轴类型');     
                    expr_Axisvalue='(?<=ST/X\s{3}).*?(?=\s*\n)'; 
                    table_Axisxvalue=regexp(select_item,expr_Axisvalue,'match');    
                    
                    
                    table_Axisxvalue_split=join(table_Axisxvalue); 
                    table_Wertvalue_splitstr=table_Axisxvalue_split;
                    table_Wertvalue_splitstr_cell=cellstr(table_Wertvalue_splitstr);
                    table_Axisxvalue_split=split(table_Axisxvalue_split," ");
                    table_Axisxvalue_split(cellfun(@isempty,table_Axisxvalue_split))=[];
                    table_Wertvalue=table_Axisxvalue;
                    table_Axisxvalue_num=num2cell(length(table_Axisxvalue_split));
                    table_Axisyvalue_num=num2cell(1);    
                   
     elseif(table_type=="FESTWERTEBLOCK")%未串轴表
                    data_parameter(1,i+1)=cellstr('未关联轴或表类型');
                    expr_Wertvalue='(?<=WERT\s{3}).*?(?=\s*\n)';
                    table_Wertvalue=regexp(select_item,expr_Wertvalue,'match'); 
                    table_Wertvalue_splitstr=join(table_Wertvalue); 
                    table_Wertvalue_splitstr_cell=cellstr(table_Wertvalue_splitstr);
                    table_Axisxvalue_num=cellstr('表与轴未相互关联');
                    table_Axisyvalue_num=cellstr('表与轴未相互关联');
                  
         end     
     data_parameter(3,i+1)=table_Wertvalue_splitstr_cell;
     data_parameter(4,i+1)=table_Axisxvalue_num;
     data_parameter(5,i+1)=table_Axisyvalue_num;
   %  table_updatevaluearray(i)=data_parameter(3,i+1);
table_Axisxvalue={};
table_Axisxvalue_split={};
table_Wertvalue={};

 end
data_parameter=data_parameter';
arrayfullpath =pwd;%mfilename('fullpath');将软件运行目录改为matlab当前工作目录
filename = [arrayfullpath,'\','DCM_array','.xlsx'];
xlRange = 'A1';
sheet ='array';
xlswrite(filename,data_parameter,sheet,xlRange);
msgbox('DCM_array   生成成功');


        end

        % Button pushed function: update_slddButton
        function update_slddButtonPushed(app, event)
           global filetextupdate;
           global tableNameAll_export;
           global table_updatevaluearray;
[file,path] = uigetfile('*.m');
           filename=[path,file]; 
filetextupdate =fileread(filename);
    for index = 1:length(tableNameAll_export)
% value 

    end
    match_str=filetextupdate;
for index = 1:length(tableNameAll_export)
    

         var_name = split(tableNameAll_export{index},".");

        if(length(var_name)==2)
           var_num=var_name(2,1);%a.b取b
        else(length(var_name)==1)
            var_num=var_name(1,1); %x轴  
        end
           %var_num=join(var_num);
           var_num_str=char(var_num);
    % value
    exp_str = ['(?<=',var_num_str,'.Value = )\w+(?=;)'];
   
    replace=char(join(table_updatevaluearray(index)));
 %   replace=table_updatevaluearray;
    match_str2 = regexp(filetextupdate,exp_str,'match');
    match_str = regexprep(match_str,exp_str,replace);


     if ~isempty(match_str2)
        var_value = match_str2{1};
    else
        var_value = '';
    end
       end
fileID = fopen('exp1.m','w');
fprintf(fileID,'%s',match_str);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu_3 = uimenu(app.UIFigure);
            app.Menu_3.Text = '菜单';
            app.Menu_4 = uimenu(app.Menu_3);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @Menu_4Selected, true);
            app.Menu_4.Text = '菜单1';
            app.Menu_5 = uimenu(app.Menu_3);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '菜单2';
            app.renameportButton = uibutton(app.UIFigure, 'push');
            app.renameportButton.ButtonPushedFcn = createCallbackFcn(app, @renameportButtonPushed, true);
            app.renameportButton.Text = 'rename port';
            app.generalinputoutputportexcelButton = uibutton(app.UIFigure, 'push');
            app.generalinputoutputportexcelButton.ButtonPushedFcn = createCallbackFcn(app, @generalinputoutputportexcelButtonPushed, true);
            app.generalinputoutputportexcelButton.Text = 'general inputoutput port excel';
            app.getportinformationButton = uibutton(app.UIFigure, 'push');
            app.getportinformationButton.ButtonPushedFcn = createCallbackFcn(app, @getportinformationButtonPushed, true);
            app.getportinformationButton.Text = 'get port information';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'picture', 'smart.jpg');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.Visible = 'off';
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'picture', 'dancing.gif');
            app.DCMButton = uibutton(app.UIFigure, 'push');
            app.DCMButton.ButtonPushedFcn = createCallbackFcn(app, @DCMButtonPushed, true);
            app.DCMButton.Text = '导入DCM';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '表名或变量名';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.ValueChangingFcn = createCallbackFcn(app, @EditField_2ValueChanging, true);
            app.CompareDCMButton = uibutton(app.UIFigure, 'push');
            app.CompareDCMButton.Text = 'Compare 两个DCM';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.DoubleClickedFcn = createCallbackFcn(app, @ListBoxDoubleClicked, true);
            app.ListBox.Value = {};
            app.ListBox_2 = uilistbox(app.UIFigure);
            app.ListBox_2.Items = {};
            app.ListBox_2.Value = {};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '当前文件扫描数：';
            app.current_filename = uieditfield(app.UIFigure, 'text');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '不看未关联A2L表';
            app.A2LSwitch = uiswitch(app.UIFigure, 'slider');
            app.A2LSwitch.ValueChangedFcn = createCallbackFcn(app, @A2LSwitchValueChanged, true);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Visible = 'off';
            app.AutoAdjustPosButton = uibutton(app.UIFigure, 'push');
            app.AutoAdjustPosButton.ButtonPushedFcn = createCallbackFcn(app, @AutoAdjustPosButtonPushed, true);
            app.AutoAdjustPosButton.Text = 'AutoAdjustPos';
            app.read_davinci_array = uibutton(app.UIFigure, 'push');
            app.read_davinci_array.ButtonPushedFcn = createCallbackFcn(app, @read_davinci_arrayButtonPushed, true);
            app.read_davinci_array.Text = 'read_davinci_array';
            app.exportparameterButton = uibutton(app.UIFigure, 'push');
            app.exportparameterButton.ButtonPushedFcn = createCallbackFcn(app, @exportparameterButtonPushed, true);
            app.exportparameterButton.Text = 'export parameter';
            app.update_slddButton = uibutton(app.UIFigure, 'push');
            app.update_slddButton.ButtonPushedFcn = createCallbackFcn(app, @update_slddButtonPushed, true);
            app.update_slddButton.Text = 'update_sldd';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = '玩狐狸';
            app.Menu_2 = uimenu(app.ContextMenu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '狐狸隐身';
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end