classdef app5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        From_m2SlddButton       matlab.ui.control.Button
        From_m2excelButton      matlab.ui.control.Button
        FromSldd2_mButton       matlab.ui.control.Button
        EditField               matlab.ui.control.EditField
        Label                   matlab.ui.control.Label
        FromEnum2SlddButton     matlab.ui.control.Button
        FromWkSpace2SlddButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FromWkSpace2SlddButton
        function FromWkSpace2SlddButtonPushed(app, event)
 global DictionaryObj;
try
     exist_flag = exist(strcat(app.EditField.Value,'.sldd'),'file');
    if(exist_flag==0)
    DictionaryObj = Simulink.data.dictionary.create(strcat(app.EditField.Value,'.sldd'));
    msgbox('创建sldd成功');
    else
    DictionaryObj = Simulink.data.dictionary.open(strcat(app.EditField.Value,'.sldd'));
    msgbox('打开现有sldd成功');
    end
    importFromBaseWorkspace(DictionaryObj);
    saveChanges(DictionaryObj);
    close(DictionaryObj);
catch
 msgbox('报异常了,不用害怕，可能是没关sldd或者已经创建sldd,把数据清空，matlab重启');
%clear classes
  
end  
        end

        % Button pushed function: FromEnum2SlddButton
        function FromEnum2SlddButtonPushed(app, event)
   global DictionaryObj;
 [file,path] = uigetfile('*.m');
filename=[path,file]; 
filetext =fileread(filename);
%expr = '(?<=Simulink.defineIntEnumType\(\s*?'').*?(?=''\,)';%匹配表名
expr = '(?<=Simulink.defineIntEnumType\(\s*?'').*?(?=''\,)';%匹配表名
EnumCell=regexp(filetext,expr,'match');%匹配表名
     exist_flag = exist(strcat(app.EditField.Value,'.sldd'),'file');
    if(exist_flag==0)
    DictionaryObj = Simulink.data.dictionary.create(strcat(app.EditField.Value,'.sldd'));
    else
    DictionaryObj = Simulink.data.dictionary.open(strcat(app.EditField.Value,'.sldd'));
    end
%EnumCell2=strip(EnumCell,'both','''');
EnumCell2=cellstr(EnumCell);
%[importedTypes,importFailures]=importEnumTypes(DictionaryObj,{'CCMLifeCycleModeGroup'});
try 
    [importedTypes,importFailures]=importEnumTypes(DictionaryObj,EnumCell2);
%numfail=length(unique(fieldnames(importFailures)));
numfail=length(importFailures);
if numfail ~=0
    for i=1:numfail
      if(isempty(importFailures(i).reason)~=1)
  {
      msgbox([importFailures(i).className,importFailures(1).reason]);
  }
      end
    end
end
catch
msgbox('导入失败');
end
msgbox('枚举导出结束');
 close(DictionaryObj);         
        end

        % Button pushed function: FromSldd2_mButton
        function FromSldd2_mButtonPushed(app, event)
       
        % select .sldd file
        [DDFile,~,~] = uigetfile('*.sldd','Select sldd file');
        DefaultFile = strcat(DDFile(1:end-5),'.m');
        if DDFile == 0
        return
        end
        % open .sldd file 
        MyDictObj = Simulink.data.dictionary.open(DDFile);
        % export data dictionary entries to mat or matlab file
        DataSectObj = getSection(MyDictObj,'Design Data');
        % export the entries from the Design Data section of sldd to a MATLAB file in current working folder.
        exportToFile(DataSectObj,DefaultFile);    
        if(isempty(MyDictObj.DataSources))
        {
             msgbox(['已针对:',DDFile,'导出M文件']);
        }
        else
        {
            msgbox(['包含数据源:',cellstr(MyDictObj.DataSources)]);
        }
        end    
       
        end

        % Button pushed function: From_m2excelButton
        function From_m2excelButtonPushed(app, event)
                  % select .m file
        [MFile,path] = uigetfile('*.m','Select .m file');  
        XLSFileName= strcat(MFile(1:end-2),'.xlsx');
        filename=[path,MFile]; 

        file_text =fileread(filename);
        sig_num=1;
% match signal
exp_str = '\w+(?= = \w+.Parameter;)';
var_str = regexp(file_text,exp_str,'match');

exp_str_singal = '\w+(?= = \w+.Signal;)';
var_str_singal = regexp(file_text,exp_str_singal,'match');

exp_str_Bus = '\w+(?= = \w+.Bus;)';
var_str_Bus = regexp(file_text,exp_str_Bus,'match');

exp_str_NumericType = '\w+(?= = \w+.NumericType;)';
var_str_NumericType = regexp(file_text,exp_str_NumericType,'match');

exp_str_AliasType = '\w+(?= = \w+.AliasType;)';
var_str_AliasType = regexp(file_text,exp_str_AliasType,'match');
strshuzu= ["singal type" "name" "value" "Complexity" "StorageClass" "Identifier"...
    "singal type" "name" "value" "Complexity" "StorageClass" "Identifier"...
    "singal type" "name" "value" "Complexity" "StorageClass" "Identifier"...
    "singal type" "name" "value" "Complexity" "StorageClass" "Identifier"];

for index = 1:length(var_str)
 
      % singaltype 
    var_name = var_str{index};
     % singaltype
    exp_str = ['(?<=',var_name,' = )\w+.\w+(?=;)'];
    match_str = regexp(file_text,exp_str,'match');
    if ~isempty(match_str)
        var_singaltype = match_str{1};
    else
        var_singaltype = '';
    end

      % value 
    var_name = var_str{index};
    % value
    exp_str = ['(?<=',var_name,'.Value = )\w+(?=;)'];
    match_str = regexp(file_text,exp_str,'match');
    if ~isempty(match_str)
        var_value = match_str{1};
    else
        var_value = '';
    end
          % Complexity 
    var_name = var_str{index};
    % Complexity
    exp_str = ['(?<=',var_name,'.Complexity = '')\w+(?='';)'];
    match_str = regexp(file_text,exp_str,'match');
    if ~isempty(match_str)
        var_Complexity = match_str{1};
    else
        var_Complexity = '';
    end 

           % StorageClass
    var_name = var_str{index};
    % storage class
    exp_str = ['(?<=',var_name,'.CoderInfo.StorageClass = '')\w+(?='';)'];
    match_str = regexp(file_text,exp_str,'match');
    if ~isempty(match_str)
        var_StorageClass = match_str{1};
    else
        var_StorageClass = '';
    end

           % Identifier
    var_name = var_str{index};
           % Identifier
    exp_str = ['(?<=',var_name,'.CoderInfo.Identifier = '')\w+(?='';)'];
    match_str = regexp(file_text,exp_str,'match');
    if ~isempty(match_str)
        var_Identifier = match_str{1};
    else
        var_Identifier = '';
    end

           % Alignment
    var_name = var_str{index};
           % Alignment
    exp_str = ['(?<=',var_name,'.CoderInfo.Alignment = )\-*\w+(?=;)'];
    match_str = regexp(file_text,exp_str,'match');
    if ~isempty(match_str)
        var_Alignment = match_str{1};
    else
        var_Alignment = '';
    end
           % CustomStorageClass
    var_name = var_str{index};
           % CustomStorageClass
    exp_str = ['(?<=',var_name,'.CoderInfo.CustomStorageClass = '')\w+(?='';)'];
    match_str = regexp(file_text,exp_str,'match');
    if ~isempty(match_str)
        var_CustomStorageClass = match_str{1};
    else
        var_CustomStorageClass = '';
    end

               % CustomAttributes.HeaderFile
    var_name = var_str{index};
               % CustomAttributes.HeaderFile
    exp_str = ['(?<=',var_name,'.CoderInfo.CustomAttributes.HeaderFile = '')\w+(?='';)'];
    match_str = regexp(file_text,exp_str,'match');
    if ~isempty(match_str)
        var_HeaderFile = match_str{1};
    else
        var_HeaderFile = '';
    end

               % CustomAttributes.ElementName
    var_name = var_str{index};
               % CustomAttributes.ElementName
    exp_str = ['(?<=',var_name,'.CoderInfo.CustomAttributes.ElementName = '')\w+(?='';)'];
    match_str = regexp(file_text,exp_str,'match');
    if ~isempty(match_str)
        var_ElementName = match_str{1};
    else
        var_ElementName = '';
    end

               % InterfacePath
    var_name = var_str{index};
               % InterfacePath
    exp_str = ['(?<=',var_name,'.CoderInfo.CustomAttributes.InterfacePath = '')\w+(?='';)'];
    match_str = regexp(file_text,exp_str,'match');
    if ~isempty(match_str)
        var_InterfacePath = match_str{1};
    else
        var_InterfacePath = '';
    end

                   % CustomAttributes.CalibrationComponent
    var_name = var_str{index};
               % CustomAttributes.CalibrationComponent
    exp_str = ['(?<=',var_name,'.CoderInfo.CustomAttributes.CalibrationComponent = '')\w+(?='';)'];
    match_str = regexp(file_text,exp_str,'match');
    if ~isempty(match_str)
        var_CalibrationComponent = match_str{1};
    else
        var_CalibrationComponent = '';
    end


               % CustomAttributes.ProviderPortName
    var_name = var_str{index};
               % CustomAttributes.ProviderPortName
    exp_str = ['(?<=',var_name,'.CoderInfo.CustomAttributes.ProviderPortName = '')\w+(?='';)'];
    match_str = regexp(file_text,exp_str,'match');
    if ~isempty(match_str)
        var_ProviderPortName = match_str{1};
    else
        var_ProviderPortName = '';
    end

               % Description
    var_name = var_str{index};
               % Description
    exp_str = ['(?<=',var_name,'.Description = '')\w+(?='';)'];
    match_str = regexp(file_text,exp_str,'match');
    if ~isempty(match_str)
        var_Description = match_str{1};
    else
        var_Description = '';
    end

    % data type
    exp_str = ['(?<=',var_name,'.DataType = '')\w+(?='';)'];
    match_str = regexp(file_text,exp_str,'match');
    if ~isempty(match_str)
        var_datatype = match_str{1};
    else
        var_datatype = [];
    end

    % minimum
    exp_str = ['(?<=',var_name,'.Min = )\w+(?=;)'];
    match_str = regexp(file_text,exp_str,'match');
    if ~isempty(match_str)
        var_min = match_str{1};
    else
        var_min = '[]';
    end
    % maximum
    exp_str = ['(?<=',var_name,'.Max = )\w+(?=;)'];
    match_str = regexp(file_text,exp_str,'match');
    if ~isempty(match_str)
        var_max = match_str{1};
    else
        var_max = '[]';
    end

               % DocUnits
    var_name = var_str{index};
               % DocUnits
    exp_str = ['(?<=',var_name,'.DocUnits = '')\w+(?='';)'];
    match_str = regexp(file_text,exp_str,'match');
    if ~isempty(match_str)
        var_DocUnits = match_str{1};
    else
        var_DocUnits = '';
    end


    %SwCalibrationAccess
    exp_str = ['(?<=',var_name,'.SwCalibrationAccess = '')\w+(?='';)'];
    match_str = regexp(file_text,exp_str,'match');
    if ~isempty(match_str)
        var_SwCalibrationAccess = match_str{1};
    else
        var_SwCalibrationAccess = '';
    end

    % DisplayFormat
    exp_str = ['(?<=',var_name,'.DisplayFormat = )\w+(?=;)'];
    match_str = regexp(file_text,exp_str,'match');
    if ~isempty(match_str)
        var_DisplayFormat = match_str{1};
    else
        var_DisplayFormat = '';
    end
    
    sig_num = sig_num + 1;
    sig_cell{sig_num,1} = var_singaltype;
    sig_cell{sig_num,2} = var_name;
    sig_cell{sig_num,3} = var_value;
    sig_cell{sig_num,4} = var_Complexity;
    sig_cell{sig_num,5} = var_StorageClass;
    sig_cell{sig_num,6} = var_Identifier;
    sig_cell{sig_num,7} = var_Alignment;
    sig_cell{sig_num,8} = var_CustomStorageClass;
    sig_cell{sig_num,9} = var_HeaderFile;
    sig_cell{sig_num,10} = var_ElementName;
    sig_cell{sig_num,11} = var_InterfacePath;
    sig_cell{sig_num,12} = var_CalibrationComponent;
    sig_cell{sig_num,13} = var_ProviderPortName;
    sig_cell{sig_num,14} = var_Description;
    sig_cell{sig_num,15} = var_datatype;
    sig_cell{sig_num,16} = var_min;
    sig_cell{sig_num,17} = var_max;
    sig_cell{sig_num,18} = var_DocUnits;
    sig_cell{sig_num,19} = var_SwCalibrationAccess;
    sig_cell{sig_num,20} = var_DisplayFormat;
end
 aa=1;
sig_cell{aa,1}='singal type';
sig_cell{aa,2}='name';
sig_cell{aa,3}='value';
sig_cell{aa,4}='Complexity';
sig_cell{aa,5}='StorageClass';
sig_cell{aa,6}='Identifier';
sig_cell{aa,7}='Alignment';
sig_cell{aa,8}='CustomStorageClass';
sig_cell{aa,9}='HeaderFile';
sig_cell{aa,10}='ElementName';
sig_cell{aa,11}='InterfacePath';
sig_cell{aa,12}='CalibrationComponent';
sig_cell{aa,13}='ProviderPortName';
sig_cell{aa,14}='Description';
sig_cell{aa,16}='datatype';
sig_cell{aa,17}='Min';
sig_cell{aa,18}='Max';
sig_cell{aa,19}='DocUnits';
sig_cell{aa,20}='SwCalibrationAccess';
sig_cell{aa,21}='DisplayFormat';
% for index = 1:length(strshuzu)   
%  { 
%      sig2_cell{1,index}=strshuzu(index);
%  }
% end
% write excel file 
xlswrite(XLSFileName,sig_cell,'Parameter','A1');

% write excel file 
xlswrite(XLSFileName,sig2_cell,'Signal','A1');

% write excel file 
xlswrite(XLSFileName,sig_cell,'Bus','A1');

% write excel file 
xlswrite(XLSFileName,sig_cell,'NumericType','A1');

% write excel file 
xlswrite(XLSFileName,sig_cell,'AliasType','A1');


        end

        % Callback function
        function clear_classesButtonPushed(app, event)
            clear classes;
        end

        % Button pushed function: From_m2SlddButton
        function From_m2SlddButtonPushed(app, event)
            clear;clc;

% save data dictionary file name uigetfile
%[DataDictName,~,~] = uiputfile('*.sldd','Save as a data dictionary file');
[DataDictName,~,~] = uiputfile('*.sldd','Save as a data dictionary file');
if DataDictName ~= 0
    exist_flag = exist(DataDictName,'file');

if exist_flag == 0
        % creat new sldd file in current working folder.
        NewDictObj = Simulink.data.dictionary.create(DataDictName);
        msgbox(['已创建新的数据字典  ',DataDictName]);
else
        % open existing sldd file .
        NewDictObj= Simulink.data.dictionary.open(DataDictName);
        msgbox(['已打开已存在的数据字典  ',DataDictName]);
    end

    % import matlab file to data dictionary 
    [FileName,~,~] = uigetfile('*.m','Import from Matlab file');
if FileName ~= 0
        DataSectObj = getSection(NewDictObj,'Design Data');
        % import matlab file
        ImportedVars = importFromFile(DataSectObj,FileName,'existingVarsAction','overwrite');
        % save changes
saveChanges(NewDictObj);

        % msgbox
msgbox('New sldd file has been created.');
    end
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FromWkSpace2SlddButton = uibutton(app.UIFigure, 'push');
            app.FromWkSpace2SlddButton.ButtonPushedFcn = createCallbackFcn(app, @FromWkSpace2SlddButtonPushed, true);
            app.FromWkSpace2SlddButton.Text = 'FromWkSpace2Sldd';
            app.FromEnum2SlddButton = uibutton(app.UIFigure, 'push');
            app.FromEnum2SlddButton.ButtonPushedFcn = createCallbackFcn(app, @FromEnum2SlddButtonPushed, true);
            app.FromEnum2SlddButton.Text = 'FromEnum2Sldd';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '模型名';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.FromSldd2_mButton = uibutton(app.UIFigure, 'push');
            app.FromSldd2_mButton.ButtonPushedFcn = createCallbackFcn(app, @FromSldd2_mButtonPushed, true);
            app.FromSldd2_mButton.Text = 'FromSldd2_.m';
            app.From_m2excelButton = uibutton(app.UIFigure, 'push');
            app.From_m2excelButton.ButtonPushedFcn = createCallbackFcn(app, @From_m2excelButtonPushed, true);
            app.From_m2excelButton.Text = 'From_.m2excel';
            app.From_m2SlddButton = uibutton(app.UIFigure, 'push');
            app.From_m2SlddButton.ButtonPushedFcn = createCallbackFcn(app, @From_m2SlddButtonPushed, true);
            app.From_m2SlddButton.Text = 'From_.m2Sldd';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end