classdef app6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image_2                 matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        Image                   matlab.ui.control.Image
        EditField               matlab.ui.control.EditField
        EditFieldLabel          matlab.ui.control.Label
        findresultListBox       matlab.ui.control.ListBox
        findresultListBoxLabel  matlab.ui.control.Label
        findparts_posButton     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: findparts_posButton
        function findparts_posButtonPushed(app, event)

            
%             Block_name=get(gcbh,'Name');
     global handle_list;
%      strlist1=split( Block_name,'_');
%      shuzugeshu1=numel(strlist1);%求数组个数
%       Model_input= strlist1(1,1); %带一个下划线直接用后面的cell即可    
%    
            findname=app.EditField.Value;
 handle_list = find_system(bdroot,'BlockType',findname);
%port=find_system(gcs,'findall','on','Type','Port','Connected','off');
%handle_list=find_system(gcs,'BlockType','Constant');
app.findresultListBox.Items=handle_list;     
        end

        % Double-clicked callback: findresultListBox
        function findresultListBoxDoubleClicked(app, event)
           global handle_list;
            item = event.InteractionInformation.Item;
            % for i=1:length(handle_list)
        path_chosen=get_param(handle_list{item,1},'Parent');
        open_system(path_chosen);
        pos=get_param(handle_list{item,1},'Position');
    for   i=1:3
         a = Simulink.Annotation(gcs,'______');
        a.FontSize = 20;
        a.BackgroundColor ='red';
        a.Position=pos;
        view(a);
        pause(0.5);
        delete(a);
    end
% end    
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            app.EditField.Value='Ground';
        end

        % Image clicked function: Image_2
        function Image_2Clicked(app, event)
            app.EditField.Value='Terminator';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.findparts_posButton = uibutton(app.UIFigure, 'push');
            app.findparts_posButton.ButtonPushedFcn = createCallbackFcn(app, @findparts_posButtonPushed, true);
            app.findparts_posButton.Text = 'findparts_pos';
            app.findresultListBoxLabel = uilabel(app.UIFigure);
            app.findresultListBoxLabel.Text = 'findresult';
            app.findresultListBox = uilistbox(app.UIFigure);
            app.findresultListBox.Items = {};
            app.findresultListBox.DoubleClickedFcn = createCallbackFcn(app, @findresultListBoxDoubleClicked, true);
            app.findresultListBox.Value = {};
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'gnd.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Terminator.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end